classdef plot_identified_lines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveButton                  matlab.ui.control.StateButton
        PressurePaListBox           matlab.ui.control.ListBox
        PressurePaListBoxLabel      matlab.ui.control.Label
        ExposureTimesListBox        matlab.ui.control.ListBox
        ExposureTimesListBoxLabel   matlab.ui.control.Label
        CurrentmAListBox            matlab.ui.control.ListBox
        CurrentmAListBoxLabel       matlab.ui.control.Label
        UpdateSavedlistButton       matlab.ui.control.Button
        SyntonyWavelengthnmListBox  matlab.ui.control.ListBox
        SyntonyWavelengthnmListBoxLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadButtonPreLoad(app, event)
             
        end

        % Callback function
        function StartStopButtonPreStart(app, event)
           
        end

        % Callback function
        function UpdatelistofidentifiedvariableButtonPushed(app, event)
            
            % app.UpdatelistofidentifiedvariableButton.Visible=1;
        end

        % Button pushed function: UpdateSavedlistButton
        function UpdateSavedlistButtonPushed(app, event)
           if exist('identified_data.mat')==0
               cc=0;
for ii=1: length(ff)
    ch=ff(ii).name;
    if ch(1)=='s'
        cc=cc+1;
        names(cc)=string(ff(ii).name);
    end
end
xPos=[];
lab=[];
for ii=1:cc
    ch=char(names(ii));
    gg=find(ch=='_');
    lmd(ii)=str2num(string(replace(ch(gg(1)+1:gg(3)-1),'_','.')));
    curr(ii)=str2num(string(replace(ch(gg(3)+1:gg(4)-1),'_','.')));
    ept(ii)=str2num(string(replace(ch(gg(5)+1:gg(7)-1),'_','.')));
    pres(ii)=str2num(string(replace(ch(gg(8)+1:gg(9)-1),'_','.')));

    fig=openfig(names(ii));
    lineObjs = findobj(fig, 'Type', 'Line');

    validIdx = false(size(lineObjs));
    for k = 1:length(lineObjs)
        if ~isempty(lineObjs(k).XData) && ~isempty(lineObjs(k).YData)
            validIdx(k) = true;
        end
    end
 lineObjs = lineObjs(validIdx);
 xx(:,ii)=lineObjs.XData;
 yy(:,ii)=lineObjs.YData;

 constObjs = findobj(fig, 'Type', 'ConstantLine');

  for jj = 1:length(constObjs)
        xPos = [xPos; constObjs(jj).Value];
        lab  = [lab; string(constObjs(jj).Label)];
  end


end
pressure=unique(pres);
wl=unique(lmd);
current=unique(curr);
exp_t=unique(ept);

[xPos,idx]=unique(xPos);
lab=lab(idx);

save('identified_data.mat','xx','yy','lab',"xPos","exp_t","current","wl","pressure",'pres','curr','ept','lmd');
            load identified_data.mat
            wl_item=string(wl);
             
            app.SyntonyWavelengthnmListBox.Items=wl_item;
            app.SyntonyWavelengthnmListBox.Visible="on";
            app.SyntonyWavelengthnmListBoxLabel.Visible="on";
            app.UpdateSavedlistButton.Visible="off";
           else
                load identified_data.mat
            wl_item=string(wl);
             
            app.SyntonyWavelengthnmListBox.Items=wl_item;
            app.SyntonyWavelengthnmListBox.Visible="on";
            app.SyntonyWavelengthnmListBoxLabel.Visible="on";
            app.UpdateSavedlistButton.Visible="off";
           end
        end

        % Value changed function: SyntonyWavelengthnmListBox
        function SyntonyWavelengthnmListBoxValueChanged(app, event)
            value = app.SyntonyWavelengthnmListBox.Value;
            load identified_data.mat
            curr_pr=unique(curr(find(lmd==str2num(string(value)))));
            pres_pr=unique(pres(find(lmd==str2num(string(value)))));
            ept_pr=unique(ept(find(lmd==str2num(string(value)))));
            app.CurrentmAListBox.Items=string(curr_pr);
            app.PressurePaListBox.Items=string(pres_pr);
            app.ExposureTimesListBox.Items=string(ept_pr);
            app.CurrentmAListBoxLabel.Visible="on";
            app.PressurePaListBoxLabel.Visible="on";
            app.ExposureTimesListBoxLabel.Visible="on";
            app.CurrentmAListBox.Visible="on";
            app.PressurePaListBox.Visible="on";
            app.ExposureTimesListBox.Visible="on";
            cla(app.UIAxes)
            app.SaveButton.Visible="off";
        end

        % Value changed function: CurrentmAListBox, ExposureTimesListBox, 
        % ...and 1 other component
        function CurrentmAListBoxValueChanged(app, event)
cla(app.UIAxes)

% --- Read and sort selected values ---
value_cur  = sort(str2double(app.CurrentmAListBox.Value));
value_pres = sort(str2double(app.PressurePaListBox.Value));
value_et   = sort(str2double(app.ExposureTimesListBox.Value));

value_lmd = str2double(app.SyntonyWavelengthnmListBox.Value);

% --- Load data ---
load identified_data.mat

% --- Find matching indices ---
idx = [];
legendEntries = {};

for ii = 1:numel(value_cur)
    for jj = 1:numel(value_pres)
        for kk = 1:numel(value_et)

            dec = lmd  == value_lmd & ...
                  pres == value_pres(jj) & ...
                  curr == value_cur(ii) & ...
                  ept  == value_et(kk);

            foundIdx = find(dec);

            if ~isempty(foundIdx)

                idx(end+1) = foundIdx(1); %#ok<SAGROW>

                legendEntries{end+1} = sprintf(...
                    'I = %.0f mA | P = %.0f Pa | exp_T = %.0f ms', ...
                    value_cur(ii), ...
                    value_pres(jj), ...
                    value_et(kk)*1000);

            end
        end
    end
end

% --- Plot spectra ---
hold(app.UIAxes, 'on')

for ii = 1:numel(idx)

    plot(app.UIAxes, ...
        xx(:,idx(ii)), ...
        yy(:,idx(ii)), ...
        'LineWidth', 2, ...
        'DisplayName', legendEntries{ii});

    % --- Plot identified spectral lines ---
    mask = xPos >= xx(1,idx(ii)) & xPos <= xx(end,idx(ii));

    xp  = xPos(mask);
    lbl = lab(mask);

   for jj = 1:numel(xp)

    hLine = xline(app.UIAxes, ...
        xp(jj), ...
        'r--', ...
        'Label', string(lbl(jj)), ...
        'LineWidth', 1.2, ...
        'LabelVerticalAlignment', 'middle');

    % Remove xline from legend
    hLine.Annotation.LegendInformation.IconDisplayStyle = 'off';

end
end

% --- Axis formatting ---
if ~isempty(idx)
    app.UIAxes.XLim = [min(xx(1,idx)) max(xx(end,idx))];
end

grid(app.UIAxes, 'on')
box(app.UIAxes, 'on')

xlabel(app.UIAxes, 'Wavelength (nm)')
ylabel(app.UIAxes, 'Intensity')

% --- Legend ---
legend(app.UIAxes, 'show', ...
    'Location', 'best', ...
    'Interpreter', 'none')

hold(app.UIAxes, 'off')

% --- Enable save button ---
app.SaveButton.Visible = "on";

        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
           % --- Save current plot ---

prompt = {'Enter file name:', ...
          'Enter extension (.png, .jpg, .pdf, .eps, etc.):'};

dlgtitle = 'Save Figure';
dims = [1 40];

defaultInput = {'SpectrumPlot', '.png'};

% --- Ask user for filename ---
answer = inputdlg(prompt, dlgtitle, dims, defaultInput);

% User pressed Cancel
if isempty(answer)
    return
end

% --- Clean filename ---
fileName = strtrim(answer{1});
fileExt  = strtrim(answer{2});

% Ensure filename is not empty
if isempty(fileName)
    uialert(app.UIFigure, ...
        'Please enter a valid file name.', ...
        'Invalid Name');
    return
end

% Remove trailing dots from filename
fileName = regexprep(fileName, '\.+$', '');

% Ensure extension starts with "."
if ~startsWith(fileExt, '.')
    fileExt = ['.' fileExt];
end

% Full output filename
fullName = [fileName fileExt];

% --- Export figure ---
try

    exportgraphics(app.UIAxes, fullName, ...
        'Resolution', 600);

    uialert(app.UIFigure, ...
        ['Figure saved successfully as "' fullName '".'], ...
        'Save Successful');

catch ME

    uialert(app.UIFigure, ...
        ['Error saving figure:' newline ME.message], ...
        'Save Error');

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Signal (arb. units)')
            zlabel(app.UIAxes, 'Z')
            app.SyntonyWavelengthnmListBoxLabel = uilabel(app.UIFigure);
            app.SyntonyWavelengthnmListBoxLabel.Visible = 'off';
            app.SyntonyWavelengthnmListBoxLabel.Text = 'Syntony Wavelength (nm)';
            app.SyntonyWavelengthnmListBox = uilistbox(app.UIFigure);
            app.SyntonyWavelengthnmListBox.ValueChangedFcn = createCallbackFcn(app, @SyntonyWavelengthnmListBoxValueChanged, true);
            app.SyntonyWavelengthnmListBox.Visible = 'off';
            app.UpdateSavedlistButton = uibutton(app.UIFigure, 'push');
            app.UpdateSavedlistButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSavedlistButtonPushed, true);
            app.UpdateSavedlistButton.Text = 'Update Saved list';
            app.CurrentmAListBoxLabel = uilabel(app.UIFigure);
            app.CurrentmAListBoxLabel.Visible = 'off';
            app.CurrentmAListBoxLabel.Text = 'Current (mA)';
            app.CurrentmAListBox = uilistbox(app.UIFigure);
            app.CurrentmAListBox.Multiselect = 'on';
            app.CurrentmAListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentmAListBoxValueChanged, true);
            app.CurrentmAListBox.Visible = 'off';
            app.CurrentmAListBox.Value = {'Item 1'};
            app.ExposureTimesListBoxLabel = uilabel(app.UIFigure);
            app.ExposureTimesListBoxLabel.Visible = 'off';
            app.ExposureTimesListBoxLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesListBox = uilistbox(app.UIFigure);
            app.ExposureTimesListBox.Multiselect = 'on';
            app.ExposureTimesListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentmAListBoxValueChanged, true);
            app.ExposureTimesListBox.Visible = 'off';
            app.ExposureTimesListBox.Value = {'Item 1'};
            app.PressurePaListBoxLabel = uilabel(app.UIFigure);
            app.PressurePaListBoxLabel.Visible = 'off';
            app.PressurePaListBoxLabel.Text = 'Pressure (Pa)';
            app.PressurePaListBox = uilistbox(app.UIFigure);
            app.PressurePaListBox.Multiselect = 'on';
            app.PressurePaListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentmAListBoxValueChanged, true);
            app.PressurePaListBox.Visible = 'off';
            app.PressurePaListBox.Value = {'Item 1'};
            app.SaveButton = uibutton(app.UIFigure, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_identified_lines

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end