classdef Optical_Parameters_Change_new_mirror < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResolutionMeasurementButton     matlab.ui.control.StateButton
        FacetotheCalibrationlampButton  matlab.ui.control.Button
        IdentifyLinesButton             matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        PixelssaturatedEditField        matlab.ui.control.EditField
        PixelssaturatedEditFieldLabel   matlab.ui.control.Label
        ChoosefittingmodelDropDown      matlab.ui.control.DropDown
        ChoosefittingmodelDropDownLabel  matlab.ui.control.Label
        CalibrateCurvatureButton        matlab.ui.control.Button
        bottom_Pixel_Value              matlab.ui.control.NumericEditField
        top_Pixel_Value                 matlab.ui.control.NumericEditField
        bottom_Pixel                    matlab.ui.control.Label
        top_Pixel                       matlab.ui.control.Label
        right_Pixel_Value               matlab.ui.control.NumericEditField
        right_Pixel                     matlab.ui.control.Label
        left_Pixel_Value                matlab.ui.control.NumericEditField
        left_Pixel                      matlab.ui.control.Label
        CameraParametersLabel           matlab.ui.control.Label
        mALabel                         matlab.ui.control.Label
        CurrentEditField                matlab.ui.control.EditField
        CurrentEditFieldLabel           matlab.ui.control.Label
        Spinner_2                       matlab.ui.control.Spinner
        LookatspectraofaddedLinesButton  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        RealTimeLabel                   matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        UITable                         matlab.ui.control.Table
        WhatdoyouwanttoseeButtonGroup   matlab.ui.container.ButtonGroup
        filenameEditField               matlab.ui.control.EditField
        CalibratedDataButton            matlab.ui.control.RadioButton
        CalibrationLampDataButton       matlab.ui.control.RadioButton
        LampDataButton                  matlab.ui.control.RadioButton
        LookatmeasuredspectraButton     matlab.ui.control.Button
        RecordthisButton                matlab.ui.control.StateButton
        CameraDropDown                  matlab.ui.control.DropDown
        CameraDropDownLabel             matlab.ui.control.Label
        LightSourceEditField            matlab.ui.control.EditField
        LightSourceEditFieldLabel       matlab.ui.control.Label
        Button_2                        matlab.ui.control.StateButton
        Button                          matlab.ui.control.StateButton
        sLabel                          matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel      matlab.ui.control.Label
        SavespectraofaddedlinesButton   matlab.ui.control.StateButton
        AddLinesButton                  matlab.ui.control.StateButton
        CHANGEVALUEButton               matlab.ui.control.StateButton
        umLabel_3                       matlab.ui.control.Label
        umLabel_2                       matlab.ui.control.Label
        umLabel                         matlab.ui.control.Label
        SlitParametersLabel             matlab.ui.control.Label
        SlitAreaEditField               matlab.ui.control.NumericEditField
        SlitAreaEditFieldLabel          matlab.ui.control.Label
        SlitHeightEditField             matlab.ui.control.NumericEditField
        SlitHeightEditFieldLabel        matlab.ui.control.Label
        SlitWidthEditField              matlab.ui.control.NumericEditField
        SlitWidthEditFieldLabel         matlab.ui.control.Label
        TecnicalParametersLabel         matlab.ui.control.Label
        RadiationSelectionMirrorLabel   matlab.ui.control.Label
        SpectrometerParametersLabel     matlab.ui.control.Label
        nmLabel_2                       matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        PresentWavelengthvalueEditField  matlab.ui.control.NumericEditField
        PresentWavelengthvalueEditFieldLabel  matlab.ui.control.Label
        TargetWavelengthEditField       matlab.ui.control.NumericEditField
        TargetWavelengthEditFieldLabel  matlab.ui.control.Label
        MirrorFacetowardsDropDown       matlab.ui.control.DropDown
        MirrorFacetowardsDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    methods (Access = private)
        function spect_cur = calculate_circular_spectra(app,img, roi)
    % Function to calculate the circular spectra from a given image or a specified region of interest (ROI).
    % img - the input image (whole image is considered if ROI is not provided)
    % roi - a 4-element vector [ylower, yupper, xlower, xupper] specifying the region of interest (ROI)

    % Circle parameters (example values, adjust as needed)
    load circle_parameters.mat
        r=rr;
    % Determine the region of interest (ROI)
    if nargin < 3  % If ROI is not provided, use the entire image
        [ylower, yupper] = deal(1, size(img, 1));  % Full image in Y dimension
        [xlower, xupper] = deal(1, size(img, 2));  % Full image in X dimension
    else
        ylower = roi(1);
        yupper = roi(2);
        xlower = roi(3);
        xupper = roi(4);
    end

    % Get the size of the region of interest
    ul_row = yupper - ylower + 1
    ul_col = xupper - xlower + 1;

    % Initialize the array for the circular spectrum
    spect_cur = zeros(1, ul_col);  % Circular spectrum

    % Loop through each column (channel) within the ROI
    for channel = xlower:xupper
        sum_cur = 0;

        % Calculate the Y-coordinate based on the circle model
        for row = ylower:yupper
            % Compute the X-coordinate using the circle equation: (x - xc)^2 + (y - yc)^2 = r^2
           xc=channel-r;
        yc = m * xc + c;

        
            % yc=m.*xc+c;   
            x = xc + sqrt(r^2 - (row - yc)^2);

            % Only calculate the curved spectrum if x >= 1
            if x >= 1
                if x > ul_col
                    sum_cur = sum_cur + double(img(row, ul_col));  % Use the last column if x exceeds image bounds
                else
                    col = floor(x);  % Integer part of x
                    frac = x - col;  % Fractional part of x
                    sum_cur = sum_cur + double(img(row, col)) * (1 - frac) + double(img(row, col + 1)) * frac;
                end
            end
        end

        % Store the average value for the current channel in the spectrum
        spect_cur(channel - xlower + 1) = sum_cur / ul_row;
    end
end
function spect_cur = calculate_parabolic_spectra(app,img, roi)
    % Function to calculate the parabolic spectra from a given image or a specified region of interest (ROI).
    % img - the input image (whole image is considered if ROI is not provided)
    % roi - a 4-element vector [ylower, yupper, xlower, xupper] specifying the region of interest (ROI)

    % Parabola parameters
    load parabola_parameters.mat

    % Determine the region of interest (ROI)
    if nargin < 3  % If ROI is not provided, use the entire image
        [ylower, yupper] = deal(1, size(img, 1));  % Full image in Y dimension
        [xlower, xupper] = deal(1, size(img, 2));  % Full image in X dimension
    else
        ylower = roi(1);
        yupper = roi(2);
        xlower = roi(3);
        xupper = roi(4);
    end

    % Get the size of the region of interest
    ul_row = yupper - ylower + 1;
    ul_col = xupper - xlower + 1;

    % Initialize the array for the parabolic spectrum
    spect_cur = zeros(1, ul_col);  % Parabolic spectrum

    % Loop through each column (channel) within the ROI
    for channel = xlower:xupper
        sum_cur = 0;

        % Calculate the vertex y-coordinate for the current channel
        yv = m * channel + c;

        % Loop through each row within the ROI
        for row = ylower:yupper
            % Compute x-coordinate based on the parabola model
            x = channel - a * (row - yv)^2;

            % Only calculate the curved spectrum if x >= 1
            if x >= 1
                if x > ul_col
                    sum_cur = sum_cur + double(img(row, ul_col));  % Use the last column if x exceeds image bounds
                else
                    col = floor(x);  % Integer part of x
                    frac = x - col;  % Fractional part of x
                    sum_cur = sum_cur + double(img(row, col)) * (1 - frac) + double(img(row, col + 1)) * frac;
                end
            end
        end

        % Store the average value for the current channel in the spectrum
        spect_cur(channel - xlower + 1) = sum_cur / ul_row;
    end
end

       function spect = capture(app, et)
    % Initialize video input object for the camera
    sat=inf;
    while sat>100
    v = videoinput("pcocameraadaptor_r2024a", 0, "USB 3.1 Gen 1");
    src = getselectedsource(v);
    
    % Set the exposure time
    src.ExposureTime_s = et;
       v.Timeout=5;
    start(v);
    
    % Capture the image

    img = getsnapshot(v);

    % Stop and clear the video input object
    stop(v);
    
    delete(v);
    clear v;
    pause(1);
    sat=length(find(img>65000));
    app.PixelssaturatedEditField.Value=string(sat);
        if app.Button.Value==1
            if sat>100
                et=et.*0.9;
                app.ExposureTimeEditField.Value=et;
            end
        end
    end
    % Calculate the spectra from the captured image
   fitmodel=app.ChoosefittingmodelDropDown.Value;
   % roi=[app.top_Pixel_Value.Value,app.bottom_Pixel_Value.Value, app.right_Pixel_Value.Value, app.left_Pixel_Value.Value];
   roi=[app.top_Pixel_Value.Value,app.bottom_Pixel_Value.Value,1,size(img,2)];
   app.right_Pixel_Value.Value=size(img,2)-app.left_Pixel_Value.Value; 
   switch fitmodel
       case 'Parabolic'
           spect=calculate_parabolic_spectra(app,img,roi);
       case 'Circular'
           spect=calculate_circular_spectra(app,img,roi);
   end
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CHANGEVALUEButton
        function CHANGEVALUEButtonValueChanged(app, event)
        

            value = app.CHANGEVALUEButton.Value;

            % Disable UI components to prevent user interaction during the operation
            disableUIComponents(app);

            % Initialize global buffer and device
            global buffer
            buffer = [0 0 0 0];  % Reset buffer
            dio = OpenDevice(9); % Open device connection on port 9

            % Define spectrometer motor
            specmotor = DefineMotor(2);
            spc=inputdlg('Enter the current wavelength (in nm):', 'Enter Current Wavelength');
            app.PresentWavelengthvalueEditField.Value=str2double(spc{1});
            % Get the current and target wavelength values
            presentLamda = app.PresentWavelengthvalueEditField.Value;
            targetLamda = app.TargetWavelengthEditField.Value;

            % Validate the current wavelength value
            presentLamda = validateCurrentWavelength(app, presentLamda);

            % Adjust the target wavelength within the acceptable range (200 - 700 nm)
            targetLamda = adjustTargetWavelength(app, targetLamda);

            % Calculate the difference between target and current wavelengths
            spin = targetLamda - presentLamda;

            % Move the motor to the target wavelength
            actualSpin = SpinMotor(dio, specmotor, spin);

            % Update the present wavelength value with the actual movement
            offset = spin - actualSpin;
            app.PresentWavelengthvalueEditField.Value = targetLamda + offset;

            % Close the device connection
            delete(dio);

            % Re-enable UI components after the operation is completed
            enableUIComponents(app);

            %% Sub-functions

            % Function to disable UI components
            function disableUIComponents(app)
                app.TargetWavelengthEditField.Enable = 0;
                app.PresentWavelengthvalueEditField.Enable = 0;
                app.SavespectraofaddedlinesButton.Enable = 0;
                app.LookatspectraofaddedLinesButton.Enable = 0;
            end

            % Function to validate the current wavelength value
            function presentLamda = validateCurrentWavelength(app, presentLamda)
                % Check if the value is empty or less than 200 nm
                while isempty(presentLamda) || presentLamda < 200 || presentLamda>700
                    % Prompt the user to enter a correct syntony for the spectrometer
                    dia = 'Enter the correct Syntony of the spectrometer';
                    spec = inputdlg(dia);
                    presentLamda = str2double(spec{1});

                    % Re-prompt if the input is invalid
                    if presentLamda < 200 || isnan(presentLamda) || isempty(presentLamda)
                        continue;
                    end

                    % Update the present wavelength value
                    app.PresentWavelengthvalueEditField.Value = presentLamda;
                end
            end

            % Function to adjust the target wavelength within the valid range
            function targetLamda = adjustTargetWavelength(app, targetLamda)
                if targetLamda < 200
                    % Prompt if the target wavelength is below 200 nm
                    questdlg('The minimum target wavelength is 200 nm.', 'OK', '');
                    targetLamda = 200;
                elseif targetLamda > 700
                    % Prompt if the target wavelength is above 700 nm
                    questdlg(['Adjust the target wavelength: ', num2str(targetLamda), ' nm manually and click OK'], 'OK', '');
                    targetLamda = 700;
                end
                % Update the target wavelength value in the app
                app.TargetWavelengthEditField.Value = targetLamda;
            end

            % Function to re-enable UI components
            function enableUIComponents(app)
                app.TargetWavelengthEditField.Enable = 1;
                app.PresentWavelengthvalueEditField.Enable = 1;
                app.SavespectraofaddedlinesButton.Enable = 1;
                app.LookatspectraofaddedLinesButton.Enable = 1;
            end
        end

        % Value changed function: MirrorFacetowardsDropDown
        function MirrorFacetowardsDropDownValueChanged(app, event)
            value = app.MirrorFacetowardsDropDown.Value;
            if string(value)=="Calibration lamp"
                final_state=1;
            elseif string(value)=="Measuring lamp"
                final_state=3;
            else
                final_state=2;
            end
            % OpenMirrorDropDownValueChanged(app);
          global buffer
            buffer = [0 0 0 0];
            dio = OpenDevice(9);
            initial_state=1;
            
            motor=DefineMotor_mirror(initial_state,final_state,1);
            spin=motor.final-motor.current;

            spin=SpinMotor_mirror(dio, motor, spin);
            app.MirrorFacetowardsDropDown.Value=value;
            delete(dio);
        end

        % Callback function: FacetotheCalibrationlampButton, 
        % ...and 1 other component
        function OpenMirrorDropDownValueChanged(app, event)
            % value = app.FacetotheCalibrationlampButton.Value;
            
            global buffer
            buffer = [0 0 0 0];
            dio = OpenDevice(9);
             value = app.MirrorFacetowardsDropDown.Value;
            if string(value)=="Calibration lamp"
                initial_state=1;

            final_state=1;
            elseif string(value)=="Measuring lamp"
                initial_state=3;

            final_state=1;
            else
                initial_state=2;

            final_state=0;
            end
            
            motor=DefineMotor_mirror(initial_state,final_state,1);
            spin=motor.final-motor.current;

            spin=SpinMotor_mirror(dio, motor, spin);
            app.MirrorFacetowardsDropDown.Value='Calibration lamp';
            delete(dio);
        end

        % Value changed function: AddLinesButton
        function AddLinesButtonValueChanged(app, event)
            app.Spinner.Visible=0;
            app.WhatdoyouwanttoseeButtonGroup.Visible=0;
            app.LookatspectraofaddedLinesButton.Enable=0;
            app.Spinner_2.Visible=0;
            if app.RecordthisButton.Value==0
                answer = questdlg('Would you like to add lines here, in a new Excel file or would you like Labo to read from an Excel file?', 'Add Lines to read', 'Add Lines here', 'Add Lines in a new Excel File','Read Lines from a Excel file','');

            else
                answer='Add Lines here';
                ss=1;
            end
            value = app.AddLinesButton.Value;

            switch answer
                case 'Add Lines here'
                    if app.RecordthisButton.Value==0
                    ss=inputdlg('Enter number of lines to be measured');
                    ss=str2num(string(ss{1}));
                    end
                    app.UITable.ColumnName=["Species","Ionization","Wavelength(nm)","Upper Energy level(cm-1)","Exposure Time","Slit Width","Slit Height","Light Source","Camera","Pressure","Resistance"];
                    app.UITable.Visible=1;
                    %% checking if slit width box is empty or not
                    if isempty(app.SlitWidthEditField.Value)==1||app.SlitWidthEditField.Value<=0||app.SlitWidthEditField.Value>1500
                        dia=['Enter the correct Slit Width of the spectrometer'];
                        spec=inputdlg(dia);
                        val=str2num(string(spec{1}));
                        while val<=0 ||val>1500|| isnan(val)==1 ||isempty(val)==1
                            dia=['Enter the correct Slit Width of the spectrometer'];
                            spec=inputdlg(dia);
                            val=str2num(string(spec{1}));
                        end
                        app.SlitWidthEditField.Value=val;
                        clear spec
                        clear val
                        clear dia
                    end
                    %% checking if slit heightbox is empty or not
                    if isempty(app.SlitHeightEditField.Value)==1||app.SlitHeightEditField.Value<=0||app.SlitHeightEditField.Value>1600
                        dia=['Enter the correct Slit Height of the spectrometer'];
                        spec=inputdlg(dia);
                        val=str2num(string(spec{1}));
                        while val<=0 ||val>1500|| isnan(val)==1 ||isempty(val)==1
                            dia=['Enter the correct Slit Height of the spectrometer'];
                            spec=inputdlg(dia);
                            val=str2num(string(spec{1}));
                        end
                        app.SlitHeightEditField.Value=val;
                        clear spec
                        clear val
                        clear dia
                    end
                    %% Checking if light source is mentioned or not
                    if isempty(app.LightSourceEditField.Value)==1
                        dia=['Enter the Light source being used'];
                        spec=inputdlg(dia);
                        val=(string(spec{1}));
                        while val==""
                            dia=['Enter the Light source being used'];
                            spec=inputdlg(dia);
                            val=(string(spec{1}));
                        end
                        app.LightSourceEditField.Value=val;
                        clear spec
                        clear val
                        clear dia
                    end


                    %% Creating the table
                    ls=string(zeros(1,ss));
                    cam=string(zeros(1,ss));
                    ls(:)=string(app.LightSourceEditField.Value);
                    cam(:)=string(app.CameraDropDown.Value);
                    sw=ones(1,ss).*app.SlitWidthEditField.Value;
                    sh=ones(1,ss).*app.SlitHeightEditField.Value;
                    et=ones(1,ss).*app.ExposureTimeEditField.Value;

                    if app.RecordthisButton.Value==0
                        lamda=zeros(1,ss);
                        sp=string(zeros(1,ss));
                        ion=string(zeros(1,ss));
                        Ue=(zeros(1,ss));
                    else
                        lamda=app.PresentWavelengthvalueEditField.Value;
                        sp="-";
                        ion="-";
                        Ue="-";
                        app.UITable.ColumnEditable=false;
                    end

                    p=zeros(1,ss);
                    r=zeros(1,ss);
                    T=table(sp',ion',lamda',Ue',et',sw',sh',ls',cam',p',r');
                    app.UITable.Data=T;

                        app.SavespectraofaddedlinesButton.Enable=1;
                        app.LookatspectraofaddedLinesButton.Enable=1;
                case 'Add Lines in a new Excel File'
                    Var=["Species*","Ionization*","Wavelength(nm)*","Upper Energy level(cm-1)*","Exposure Time*","Slit Width","Slit Height","Light Source","Camera","Pressure","Resistance"];
                    ss=uigetdir(pwd,'Choose folder to save the excel file');
                    dia=['Enter name of excel file'];
                    spec=inputdlg(dia);
                    name=spec{1};
                    file1=[ss,'\',name,'.xlsx'];
                    writematrix(Var,file1,'Sheet','Measuring Parameters');
                    questdlg('Go to the excel file and enter the details of lines to be measured(* indicates mandatory field) and click on "Add Lines"','Instruction','Okay','','Okay');
                case 'Read Lines from a Excel file'
                    [file,path] = uigetfile('*.xlsx','File Selector');
                    file1=[path file];
                    T=readtable(file1,'Sheet','Measuring Parameters');
                    app.UITable.ColumnName=["Species","Ionization","Wavelength(nm)","Upper Energy level(cm-1)","Exposure Time","Slit Width","Slit Height","Light Source","Camera","Pressure","Resistance"];
                    app.UITable.Visible=1;
                    app.UITable.Data=T;
                    
                        app.SavespectraofaddedlinesButton.Enable=1;
                        app.LookatspectraofaddedLinesButton.Enable=1;

            end
            app.AddLinesButton.Value=0;
        end

        % Value changed function: SavespectraofaddedlinesButton
        function SavespectraofaddedlinesButtonValueChanged2(app, event)
   % Disable UI components to prevent interaction during the process
            disableUIComponents(app);
            load dispersion.mat lambd theo_disp

            % Retrieve data from the table in the app UI
            
            T = app.UITable.Data;
            [Species, Ionisation, lamda, Upper_Energy, Exposure_Time, Slit_width, ...
                Slit_height, Light_source, Camera] = extractTableData(T);
            % lamda=str2double(lambda);
            % Prompt user to select a directory for saving data
            saveDirectory = pwd;
            saveDirectory=saveDirectory(1:end-4);
            % Generate a filename based on the current settings and time
            filename = generateFilename(app,Light_source, Camera, Slit_width);
           
            % Add additional specifications to the filename if needed
            filename = addSpecificationsToFilename(app, filename);

            % Define the full path to save the Excel file
            filePath = fullfile(saveDirectory, [filename, '.xlsx']);

            % Initialize channel data for spectrometer readings
            channel = [app.left_Pixel_Value.Value:app.right_Pixel_Value.Value]';
          
            % Create headers for data sheets
            createHeaders(T, filePath);

            % Loop through each wavelength and perform measurements
            for ii = 1:length(lamda)
                % Measure the spectrum for the current wavelength
                spect = captureSpectra(app, lamda(ii), Exposure_Time(ii))./Exposure_Time(ii);


                % Measure the calibrated lamp for the current wavelength
                calib=captureCalib(app, Exposure_Time(ii))./Exposure_Time(ii);

                % Measure background signal
                bg = captureBackground(app, Exposure_Time(ii))./Exposure_Time(ii);

                % Calculate corrected spectrum
                spectcorr = spect - bg;

                 % Calculate corrected calibrated spectrum
                calibcorr = calib - bg;

                % Write the data to Excel
                yy=interp1(lambd,theo_disp,lamda(ii))*10^9;
                
                wave=lamda(ii)+yy.*(channel-channel(round(length(channel)/2)));
                writeSpectraToExcel(filePath, spect, bg, spectcorr,calib,calibcorr, ii, size(T,2),wave);

                % Plot the corrected spectrum
                plot(app.UIAxes, spectcorr(1:end), 'LineWidth', 1.5);
                % xlabel(app.UIAxes, 'Wavelength (nm)');
            end
            rang = sprintf(['A%d:A%d'], size(T,2)+1,size(T,2)+app.right_Pixel_Value.Value-app.left_Pixel_Value.Value);
            writematrix(channel, filePath, "Sheet", 'Data', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Background', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Corrected Data', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Wavelength', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Calibration Lamp Data', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Corrected Calibration Lamp Data', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Calibrated Lamp Data', 'Range', rang);


            % Write measurement parameters to the Excel file
            writeMeasurementParameters(filePath, T, lamda);

            % Re-enable UI components after process completion
            enableUIComponents(app);

            %% Sub-functions

            % Function to disable UI components
            function disableUIComponents(app)
                app.Spinner.Visible = 0;
                app.WhatdoyouwanttoseeButtonGroup.Visible = 0;
                app.LookatspectraofaddedLinesButton.Enable = 0;
                app.Spinner_2.Visible = 0;
                app.UITable.ColumnEditable = false;
                app.RecordthisButton.Enable = 0;
                app.AddLinesButton.Enable = 0;
                app.RealTimeLabel.Enable = 0;
                app.SpectrometerParametersLabel.Enable = 0;
                app.TargetWavelengthEditField.Enable = 0;
                app.PresentWavelengthvalueEditField.Enable = 0;
                app.Button.Enable = 0;
                app.Button_2.Enable = 0;
                app.LookatmeasuredspectraButton.Enable = 0;
            end

            % Function to extract data from the table
            function [Species, Ionisation, lamda, Upper_Energy, Exposure_Time, Slit_width, Slit_height, Light_source, Camera] = extractTableData(T)
                Species = T{:, 1}';
                Ionisation = T{:, 2}';
                lamda = T{:, 3};
                Upper_Energy = T{:, 4}';
                Exposure_Time = T{:, 5}';
                Slit_width = T{:, 6}';
                Slit_height = T{:, 7}';
                Light_source = T{:, 8}';
                Camera = T{:, 9}';
            end

            % Function to generate a filename based on the light source, camera, and slit width
            function filename = generateFilename(app, Light_source, Camera, Slit_width)
                timestamp = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
                
                % Check and retrieve Current value
                if strcmp(Light_source{1}, 'Hollow Cathode lamp') || strcmp(Light_source{1}, 'HCL')

                if isempty(app.CurrentEditField.Value)
                     currentInput = inputdlg('Enter the current value (in mA):', 'Current Input');
                     app.CurrentEditField.Value = currentInput{1}; % Update UI    
                end
                currentValue = str2double(string(app.CurrentEditField.Value));
                
                % Check and retrieve Voltage value
                    voltageInput = inputdlg('Enter the voltage value (in V):', 'Voltage Input');
                    
                voltageValue =str2double(string(voltageInput{1}));
                
                % Generate filename with additional parameters
                filename = sprintf('%s_%s_%dum__%dmA_%dV_%s', ...
                    Light_source{1}, Camera{1}, Slit_width(1), ...
                    currentValue, voltageValue, string(timestamp));
                else
                       % Generate filename without current and voltage
        filename = sprintf('%s_%s_%dum_%s',string(Light_source{1}), string(Camera{1}), Slit_width(1), string(timestamp));
    end
            end
            % Function to add specifications to the filename
            function filename = addSpecificationsToFilename(app, filename)
                if app.RecordthisButton.Value == 0
                    spec = inputdlg(['Add more specifications to ', filename]);
                    if ~isempty(spec{1})
                        filename = [filename, '_', spec{1}];
                    end
                else
                    filename = [filename, '_Real_Time_Capture'];
                end
            end

            % Function to create headers in the Excel file
            % Function to create headers and write corresponding values for all rows in the table to the Excel file
            function createHeaders(T, filePath)
                % Define headers
                headers = ["Species", "Ionization", "Wavelength(nm)", "Upper Energy level(cm-1)", ...
                    "Exposure Time", "Slit Width", "Slit Height", "Light Source", ...
                    "Camera", "Pressure", "Resistance"];


                % Write headers to Excel, starting at cell A1
                hdr_rang=string(['A1:A' char(string(length(headers)))]);
                filePath
                writematrix(headers', filePath, 'Sheet', 'Data', 'Range', hdr_rang);
                writematrix(headers', filePath, 'Sheet', 'Background', 'Range', hdr_rang);
                writematrix(headers', filePath, 'Sheet', 'Corrected Data', 'Range', hdr_rang);
                writematrix(headers', filePath, 'Sheet', 'Wavelength', 'Range', hdr_rang);
                writematrix(headers', filePath,"Sheet", 'Calibration Lamp Data', 'Range', hdr_rang);
                writematrix(headers', filePath,"Sheet", 'Corrected Calibration Lamp Data', 'Range', hdr_rang);
                writematrix(headers', filePath,"Sheet", 'Calibrated Lamp Data', 'Range', hdr_rang);

                % writematrix(headers', filePath,"Sheet", 'Calibration Lamp Data', 'Range', hdr_rang);
                % writematrix(headers', filePath,"Sheet", 'Corrected Calibration Lamp Data', 'Range', hdr_rang);
                % writematrix(headers', filePath,"Sheet", 'Calibrated Lamp Data', 'Range', hdr_rang);
                % Determine the number of rows in T
                numRows = size(T, 1);

                % Extract all values from table T for the specified columns
                T1=rows2vars(T);
                T1=T1(:,2:end);
                if numRows<26
                    range=['B1:' 'B'+numRows-1 char(string(length(headers)))];
                else
                    range=['B1:' char(['B'+floor(numRows/26)-2 'A'+mod(numRows,26)]) char(string(length(headers)))];
                end
                writetable(T1, filePath,'Sheet','Data','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,'Sheet','Background','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,'Sheet','Corrected Data','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,'Sheet','Wavelength','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,"Sheet", 'Calibration Lamp Data', 'Range', range,'WriteVariableNames', false);
                writetable(T1, filePath,"Sheet", 'Corrected Calibration Lamp Data', 'Range', range,'WriteVariableNames', false);
                writetable(T1, filePath,"Sheet", 'Calibrated Lamp Data', 'Range', range,'WriteVariableNames', false);
                
           end


            % Function to measure spectra
            function spect = captureSpectra(app, lamda, exposureTime)
                
                OpenMirrorDropDownValueChanged(app);
                app.MirrorFacetowardsDropDown.Value = 'Measuring lamp';
                MirrorFacetowardsDropDownValueChanged(app);
                app.TargetWavelengthEditField.Value = lamda;
                CHANGEVALUEButtonValueChanged(app);
                spect = capture(app, exposureTime);
                spect = spect(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
            end

            % Function to capture background
            function bg = captureBackground(app, exposureTime)
                OpenMirrorDropDownValueChanged(app);
                app.MirrorFacetowardsDropDown.Value = 'Spectrometer';
                MirrorFacetowardsDropDownValueChanged(app);
                bg = capture(app, exposureTime);
                bg = bg(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
            end
              % Function to capture calibrated lamp signal
            function calib = captureCalib(app, exposureTime)
                OpenMirrorDropDownValueChanged(app);
                app.MirrorFacetowardsDropDown.Value = 'Calibration lamp';
                MirrorFacetowardsDropDownValueChanged(app);
                calib = capture(app, exposureTime);
                calib = calib(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
            end

            % Function to write spectra and wavelength to Excel
            function writeSpectraToExcel(filePath, spect, bg, spectcorr,calib,calib_corr, ii, ll, wave)
    % Load dispersion data
    load dispersion.mat;
    tt=readtable('Calibration.xlsx');
    cal_I=tt.Irradiance_mWM_2Nm_1_;
    cal_lambda=tt.Wavelength_nm_;
    cal_I_interp_spline = interp1(cal_lambda, cal_I, wave, 'spline');

    % Get the correct Excel column based on ii
    col = getExcelColumn(ii);

    % Define the row range
    startRow = ll + 1;
    endRow = app.right_Pixel_Value.Value - app.left_Pixel_Value.Value + ll;
    
    % Define the range in Excel notation
    range = sprintf('%s%d:%s%d', col, startRow, col, endRow);
  
    % Write data to different sheets in the Excel file
    writematrix(spect', filePath, "Sheet", 'Data', 'Range', range);
    writematrix(bg', filePath, "Sheet", 'Background', 'Range', range);
    writematrix(spectcorr', filePath, "Sheet", 'Corrected Data', 'Range', range);
    writematrix(calib', filePath, "Sheet", 'Calibration Lamp Data', 'Range', range);
    writematrix(calib_corr', filePath, "Sheet", 'Corrected Calibration Lamp Data', 'Range', range);
    writematrix(spectcorr'.*cal_I_interp_spline./calib_corr', filePath, "Sheet", 'Calibrated Lamp Data', 'Range', range);
    writematrix(wave, filePath, "Sheet", 'Wavelength', 'Range', range);
end

% Function to convert column index (ii) to Excel-style column letters
function colName = getExcelColumn(ii)
    colName = '';
    while ii >= 0
        remainder = mod(ii, 26);
        colName = [char('A' + remainder), colName]; % Prepend character
        ii = floor(ii / 26) - 1; % Move to next place value
    end
end


            % Function to write measurement parameters
            function writeMeasurementParameters(filePath, T, lamda)
                range = ['A1:' 'A'+size(T,2) char(string(length(lamda) + 1))];
                T.Properties.VariableNames=[{'Species'} {'Ionization'} {'Wavelength(nm)'} {'Upper Energy level(cm-1)'} ...
                    {'Exposure Time'} {'Slit Width'} {'Slit Height'} {'Light Source'} {'Camera'} ...
                    {'Pressure'} {'Resistance'}];
                writetable(T, filePath, "Sheet", 'Measuring Parameters', 'Range', range);
            end

            % Function to re-enable UI components
            function enableUIComponents(app)
                app.SavespectraofaddedlinesButton.Value = 0;
                app.RecordthisButton.Enable = 1;
                app.AddLinesButton.Enable = 1;
                app.RealTimeLabel.Enable = 1;
                app.SpectrometerParametersLabel.Enable = 1;
                app.LookatmeasuredspectraButton.Enable = 1;
                app.LookatspectraofaddedLinesButton.Enable = 1;
                app.UITable.ColumnEditable = true;
                app.TargetWavelengthEditField.Enable = 1;
                app.PresentWavelengthvalueEditField.Enable = 1;
                app.Button.Enable = 1;
                app.Button_2.Enable = 1;
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            valuestart = app.Button.Value;
            et = app.ExposureTimeEditField.Value;
            app.RecordthisButton.Visible = 1;

            while valuestart == 1
                 et = app.ExposureTimeEditField.Value;
                % Disable specific UI elements during the capture process
                disableUIComponents(app);

                % Capture the spectra without saving the image
                spect = capture(app, et);

                % Plot the spectra
                plot(app.UIAxes, spect(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value), 'LineWidth', 1.5);
                grid(app.UIAxes, 'on');

                % Check if Button_2 is pressed to stop the process
                if app.Button_2.Value == 1
                    % Reset button states
                    app.Button_2.Value = 0;
                    app.Button.Value = 0;
                    valuestart = 0;

                    % Re-enable UI components after capture
                    enableUIComponents(app);

                    % Capture spectra again if needed
                    spect = capture(app, et);

                    % Conditionally enable specific buttons based on UITable visibility
                    if app.UITable.Visible == 1
                        app.SavespectraofaddedlinesButton.Enable = 1;
                        app.LookatspectraofaddedLinesButton.Enable = 1;
                    end
                    break;
                end
            end

            % Function to disable relevant UI components
            function disableUIComponents(app)
                app.AddLinesButton.Enable = 0;
                app.SavespectraofaddedlinesButton.Enable = 0;
                app.RecordthisButton.Enable = 0;
                app.LookatmeasuredspectraButton.Enable = 0;
                app.Spinner_2.Visible = 0;
                app.Spinner.Visible = 0;
                app.WhatdoyouwanttoseeButtonGroup.Visible = 0;
                app.LookatspectraofaddedLinesButton.Enable = 0;
                app.TextArea.Visible = 1;
            end

            % Function to enable relevant UI components
            function enableUIComponents(app)
                app.AddLinesButton.Enable = 1;
                app.RecordthisButton.Enable = 1;
                app.LookatmeasuredspectraButton.Enable = 1;
                app.TextArea.Visible = 0;
            end

        end

        % Button pushed function: LookatmeasuredspectraButton
        function LookatmeasuredspectraButtonPushed(app, event)
            c=pwd;
            [p,ss]=uigetfile([c '\*.xlsx']);
            if p~=0
                app.WhatdoyouwanttoseeButtonGroup.Visible=1;
                app.filenameEditField.Value=[ss p];

                T=xlsread(p,'Corrected Data');
                no_lines=width(T)-1;
                app.Spinner.Limits=[1,no_lines];
                app.Spinner.Value=1;
                app.Spinner.Visible=1;
                WhatdoyouwanttoseeButtonGroupSelectionChanged(app, event);
            end
        end

        % Callback function: Spinner, WhatdoyouwanttoseeButtonGroup
        function WhatdoyouwanttoseeButtonGroupSelectionChanged(app, event)
            selectedButton = app.WhatdoyouwanttoseeButtonGroup.SelectedObject;
            sheet='';
            if app.LampDataButton.Value==1
                sheet='Corrected Data';
            elseif app.CalibrationLampDataButton.Value==1
                sheet='Corrected Calibration Lamp Data';
            elseif app.CalibratedDataButton.Value==1
                sheet='Calibrated Lamp Data';
            end
            p=app.filenameEditField.Value;
            ColumnName=["Species","Ionization","Wavelength(nm)","Upper Energy level(cm-1)","Exposure Time","Slit Width","Slit Height","Light Source","Camera","Pressure","Resistance"];
            T=readtable(p,'Sheet','Measuring Parameters');
            app.UITable.ColumnName=ColumnName;
            app.UITable.Visible=1;
             no_spect=app.Spinner.Value;
            app.UITable.Data=T(no_spect,:);
            app.UITable.ColumnEditable=false;

           
            range=[char('A'+no_spect) char(string(size(ColumnName,2)+1)) ':' char('A'+no_spect) char(string(app.right_Pixel_Value.Value-app.left_Pixel_Value.Value+size(ColumnName,2)))];
            data1=readtable(p,'Sheet',sheet,'Range',range);
            data=data1{:,:};
            sheets=sheetnames(p);
            if any(strcmp(sheets, 'Wavelength'))
                 wave1=readtable(p,'Sheet','Wavelength','Range',range);
                 plot(app.UIAxes,data,LineWidth=1.5)
            else
                plot(app.UIAxes,data,LineWidth=1.5)
            end
            
        end

        % Value changed function: RecordthisButton
        function RecordthisButtonValueChanged(app, event)
            app.Spinner.Visible=0;
            app.WhatdoyouwanttoseeButtonGroup.Visible=0;
            app.LookatspectraofaddedLinesButton.Enable=0;
            app.Spinner_2.Visible=0;
            app.AddLinesButton.Enable=0;
           app.RealTimeLabel.Enable=0;
           app.LookatmeasuredspectraButton.Enable=0;
           app.LookatspectraofaddedLinesButton.Enable=0;
           app.SpectrometerParametersLabel.Enable=0;
           app.TargetWavelengthEditField.Enable=0;
            app.PresentWavelengthvalueEditField.Enable=0;
            app.Button.Enable=0;
            app.Button_2.Enable=0;
            %% Checking if present syntony is mentioned or not
            if isempty(app.PresentWavelengthvalueEditField.Value)==1||app.PresentWavelengthvalueEditField.Value<200
                dia=['Enter the correct Syntony of the spectrometer'];
                spec=inputdlg(dia);
                val=str2num(string(spec{1}));
                while val<200 || isnan(val)==1 ||isempty(val)==1
                    dia=['Enter the correct Syntony of the spectrometer'];
                    spec=inputdlg(dia);
                    val=str2num(string(spec{1}));
                end
                app.PresentWavelengthvalueEditField.Value=val;
                app.TargetWavelengthEditField.Value=val;
                clear spec
                clear val
                clear dia
            end
            AddLinesButtonValueChanged(app, event);
            SavespectraofaddedlinesButtonValueChanged2(app,event);
           app.SavespectraofaddedlinesButton.Enable=0;
           app.RecordthisButton.Value=0;
           app.AddLinesButton.Enable=1;
           app.RealTimeLabel.Enable=1;
           app.SpectrometerParametersLabel.Enable=1;
            app.TargetWavelengthEditField.Enable=1;
            app.PresentWavelengthvalueEditField.Enable=1;
            app.Button.Enable=1;
            app.Button_2.Enable=1;
            if app.UITable.Visible==1
                app.SavespectraofaddedlinesButton.Enable=1;
                app.LookatspectraofaddedLinesButton.Enable=1;
            end
        end

        % Callback function: LookatspectraofaddedLinesButton, Spinner_2
        function LookatspectraofaddedLinesButtonPushed(app, event)
            app.Spinner.Visible=0;
            app.WhatdoyouwanttoseeButtonGroup.Visible=0;
            app.LookatspectraofaddedLinesButton.Enable=0;
            app.Spinner_2.Visible=1;
            app.UITable.ColumnEditable=false;
            app.RecordthisButton.Enable=0;
            app.AddLinesButton.Enable=0;
            app.RealTimeLabel.Enable=0;
            app.SpectrometerParametersLabel.Enable=0;
            app.TargetWavelengthEditField.Enable=0;
            app.PresentWavelengthvalueEditField.Enable=0;
            app.Button.Enable=0;
            app.Button_2.Enable=0;
            app.LookatmeasuredspectraButton.Enable=0;
            % Entering data in table for measurement
            T=app.UITable.Data;
            lamda=T{:,3};
            app.Spinner_2.Limits=[1 length(lamda)];
            lamda=lamda(app.Spinner_2.Value);
            Exposure_Time=T{:,5}';
            Exposure_Time=Exposure_Time(app.Spinner_2.Value);
            for ii=1:length(lamda)
                measure(lamda(ii));
                spect=capture(app,Exposure_Time(ii))./Exposure_Time(ii);
                spect=spect(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
                %% Background measurement
                app.MirrorFacetowardsDropDown.Value='No';
               
                OpenMirrorDropDownValueChanged(app, event)
                MirrorFacetowardsDropDownValueChanged(app, event)

                bg=capture(app,Exposure_Time(ii))./Exposure_Time(ii);
                bg=bg(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
                
                plot(app.UIAxes,spect-bg,LineWidth=1.5) 
                pause() 
            end
           
            app.MirrorFacetowardsDropDown.Value='Yes';
            OpenMirrorDropDownValueChanged(app, event)
            MirrorFacetowardsDropDownValueChanged(app, event)
            app.SavespectraofaddedlinesButton.Value=0;
            app.RecordthisButton.Enable=1;
            app.AddLinesButton.Enable=1;
            app.RealTimeLabel.Enable=1;
            app.SpectrometerParametersLabel.Enable=1;
            app.LookatmeasuredspectraButton.Enable=1;
            app.LookatspectraofaddedLinesButton.Enable=1;
            app.UITable.ColumnEditable=true;
            app.TargetWavelengthEditField.Enable=1;
            app.PresentWavelengthvalueEditField.Enable=1;
            app.Button.Enable=1;
            app.Button_2.Enable=1;
            function measure(lmd)
                app.MirrorFacetowardsDropDown.Value='Yes';
                OpenMirrorDropDownValueChanged(app, event)
                MirrorFacetowardsDropDownValueChanged(app, event)
                app.TargetWavelengthEditField.Value=lmd;
                CHANGEVALUEButtonValueChanged(app, event)
            end
        end

        % Button pushed function: CalibrateCurvatureButton
        function CalibrateCurvatureButtonPushed(app, event)
% Calibration Dialog
answer = questdlg('Use a LASER and place it in front of the spectrometer and switch it on. Tune the Spectrometer to the wavelength of the LASER. Go to the Camera App. See the real-time image. Change the tuning with hand so that the image of the entrance slit formed by the LASER on the camera is on the extreme left of the camera. Capture the image by changing the tuning by 0.1 nm and save them as .tif files. Click OK if you want to capture the images, OR click Calibrate if the images are already captured.', 'Calibration', 'OK', 'Calibrate', '');

switch answer
    case 'OK'
        clear all; close all; clc; exit;
        
    case 'Calibrate'
        ans1 = app.ChoosefittingmodelDropDown.Value;
        
        % Image selection
        [image_files, image_path] = uigetfile('*.tif', 'Select .tiff Images', 'MultiSelect', 'on');
        if isequal(image_files, 0)
            disp('User canceled image selection.');
            return;
        end
        if ischar(image_files), image_files = {image_files}; end

        % Get Region of Interest (ROI) from the user
        image = imread(fullfile(image_path, image_files{1}));
        imshow(image * 5, 'parent', app.UIAxes);
        questdlg('Choose lowest row where the slit image is visible', 'OK', '');
        [~, roi1] = ginput(1);
        questdlg('Choose highest row where the slit image is visible', 'OK', '');
        [~, roi2] = ginput(1);
        
        if isempty(roi1) || isempty(roi2)
            disp('User canceled ROI input.');
            return;
        end
        if roi1 > roi2, [roi1, roi2] = deal(roi2, roi1); end
        
        ylower = str2double(roi1);
        yupper = str2double(roi2);
        
        % Common processing for images
        [ul_row, ul_col, all_maxima, num_images] = process_images(image_files, image_path, ylower, yupper, app.UIAxes);

        % Switch between calibration models
        switch ans1
            case 'Circular'
                fit_circular_model(num_images, all_maxima, ul_row, app.UIAxes);
                
            case 'Parabolic'
                fit_parabolic_model(num_images, all_maxima, ul_row, app.UIAxes);
        end
end

% Function to process images and find maxima
function [ul_row, ul_col, all_maxima, num_images] = process_images(image_files, image_path, ylower, yupper, ui_axes)
    num_images = length(image_files);
    all_maxima = [];
    
    for i = 1:num_images
        image = imread(fullfile(image_path, image_files{i}));
        [ul_row, ul_col] = size(image);

        % Ensure ROI is within image boundaries
        yupper = min(yupper, ul_row);

        maxima = find_row_maxima(image);
        all_maxima = [all_maxima; maxima]; % Append maxima
    end
    
    % Plot the observed maxima
    imshow(zeros(ul_row, ul_col), 'parent', ui_axes); hold on;
    plot(ui_axes, all_maxima(:, 2), all_maxima(:, 1), 'ro', 'MarkerSize', 2); % Observed maxima
end

% Function to find row maxima in an image
function maxima = find_row_maxima(image)
    [~, max_idx] = max(image, [], 2); % Column indices of max value in each row
    maxima = [(1:size(image, 1))', max_idx]; % [Row index, Max column index]
end

% Function to fit circular model
function fit_circular_model(num_images, all_maxima, ul_row, ui_axes)
    rr = zeros(num_images, 1);    % Radius
    xxv = zeros(num_images, 1);   % X center
    yyv = zeros(num_images, 1);   % Y center
    sigma = zeros(1, num_images); % RMSE for each fit
    
    % Anonymous function for circle fitting: sqrt((x - xx).^2 + (y - yy).^2) - r
    circleFit = @(params, x, y) sqrt((x - params(1)).^2 + (y - params(2)).^2) - params(3);
    
    for ii = 1:num_images
        [x, y, idx] = get_image_data(all_maxima, ii, ul_row);

        % Initial guess: [xx, yy, r] -> [mean(x), mean(y), range(x) / 2]
        initial_guess = [mean(x), mean(y), range(x) / 2];

        % Set lower and upper bounds
        lb = [-Inf, -Inf, 0];  % X center can be any value, but radius must be > 0
        ub = [0, Inf, Inf];    % X center <= 0, Y center can be any value, radius unrestricted upwards
        
        % Fit the circle with bounds
        options = optimset('Display', 'off');
        fit_params = lsqnonlin(@(params) circleFit(params, x, y), initial_guess, lb, ub, options);

        % Store fitted parameters
        xxv(ii) = fit_params(1);  % X center
        yyv(ii) = fit_params(2);  % Y center
        rr(ii) = fit_params(3);   % Radius

        % Generate theoretical maxima based on the fit
        theta = linspace(0, 2 * pi, 100);
        theoretical_x = xxv(ii) + rr(ii) * cos(theta);
        theoretical_y = yyv(ii) + rr(ii) * sin(theta);

        % Plot fitted circular arc
        plot(ui_axes, theoretical_x, theoretical_y, 'b-', 'LineWidth', 1.5);

        % Calculate RMSE for the fit
        sigma(ii) = sqrt(mean(circleFit(fit_params, x, y).^2)); % RMSE
    end

    legend('Observed Maxima', 'Fitted Circle', 'Location', 'Best');
    hold off;

    % Polynomial fit for xxv and yyv (X and Y centers)
    [m,c]=fit_polynomial(xxv, yyv);
    
    % Save the fitted parameters
    rr=mean(rr);
    save('circle_parameters.mat', 'rr', 'm', 'c');
end


% Function to fit parabolic model
function fit_parabolic_model(num_images, all_maxima, ul_row, ui_axes)
    aa = zeros(num_images, 1);
    xxv = zeros(num_images, 1);
    yyv = zeros(num_images, 1);
    sigma = zeros(1, num_images);
    
    ft = fittype('xv - a*(x - yv).^2', 'independent', 'x', 'dependent', 'y');
    opts = fitoptions('Method', 'NonlinearLeastSquares', 'Display', 'Off', 'Lower', [0, -Inf, -Inf]);
    
    for ii = 1:num_images
        [x, y, idx] = get_image_data(all_maxima, ii, ul_row);
        
        % Fit parabola
        [fitresult, gof] = fit(x, y, ft, opts);

        % Store fitted parameters
        aa(ii) = fitresult.a;
        xxv(ii) = fitresult.xv;
        yyv(ii) = fitresult.yv;

        % Plot fitted curve
        theoretical_y = fitresult.xv - fitresult.a * (x - fitresult.yv).^2;
        plot(ui_axes, real(theoretical_y), x, 'b-', 'LineWidth', 1.5);

        sigma(ii) = gof.rmse;
    end

    legend('Observed Maxima', 'Fitted Curve', 'Location', 'Best');
    hold off;

    % Polynomial fit for xxv and yyv
    [m,c]=fit_polynomial(xxv, yyv);
    aa=mean(aa);
    save('parabola_parameters.mat', 'aa', 'm', 'c');
end

% Function to get data for an image
function [x, y, idx] = get_image_data(all_maxima, ii, ul_row)
    idx_start = (ii - 1) * ul_row + 1;
    idx_end = min(ii * ul_row, size(all_maxima, 1));
    idx = idx_start:idx_end;
    
    x = all_maxima(idx, 2); % Maxima column indices
    y = all_maxima(idx, 1); % Row indices
end

% Function to fit a polynomial curve for xxv and yyv
            function [m,c]= fit_polynomial(xxv, yyv)
    [xData, yData] = prepareCurveData(xxv, yyv);
    [fitresult, ~] = fit(xData, yData, 'poly1');
    
    m = fitresult.p1;
    c = fitresult.p2;
end


        end

        % Callback function
        function CalculateResponseFunctionButtonValueChanged(app, event)
            % value = app.CalculateResponseFunctionButton.Value;
            % lmd_strt=200;
            % dd=pwd;
            % dd=[dd(1:end-4) 'Response function calibration\response.']
        end

        % Button pushed function: IdentifyLinesButton
        function IdentifyLinesButtonPushed(app, event)
            choice = questdlg('Close this app only if the measurements are complete. Do you want to close this app?', ...
                      'Confirm Close', ...
                      'Yes', 'No', 'No');
    if strcmp(choice, 'Yes')
        
        delete(app);            % Close parent app
    end
    dd=pwd;     
    cd(dd(1:end-3));
    run([dd(1:end-3) '\identify_lines.mlapp']);

        end

        % Value changed function: ResolutionMeasurementButton
        function ResolutionMeasurementButtonValueChanged(app, event)
            value = app.ResolutionMeasurementButton.Value;
             % Disable UI components to prevent interaction during the process
            disableUIComponents(app);
            load dispersion.mat lambd theo_disp
            app.UITable.ColumnName=["Species","Ionization","Wavelength(nm)","Upper Energy level(cm-1)","Exposure Time","Slit Width","Slit Height","Light Source","Camera","Pressure","Resistance"];
                    app.UITable.Visible=1;
                    ss=length(5:5:60);
                    ls=string(zeros(1,ss));
                    
                    cam=string(zeros(1,ss));
                    ls(:)="Laser";
                    Ue(:)=repmat("-", 1, ss);
                    p=repmat("-", 1, ss);
                    r=repmat("-", 1, ss);
                    sp = repmat("-", 1, ss);
                    ion = repmat("-", 1, ss);

                    cam(:)=string(app.CameraDropDown.Value);
                    sw=ones(1,ss).*[5:5:60];
                    sh=ones(1,ss).*1500;
                    app.ExposureTimeEditField.Value=0.002;
                    et=ones(1,ss).*app.ExposureTimeEditField.Value;

                   ff=inputdlg('Enter the wavelength of the Laser');
                   lamda=ones(1,ss).*str2num(char(ff));


                    p=zeros(1,ss);
                    r=zeros(1,ss);
                    T=table(sp',ion',lamda',Ue',et',sw',sh',ls',cam',p',r');
                    app.UITable.Data=T;

            % Retrieve data from the table in the app UI
            
            T = app.UITable.Data;
            [Species, Ionisation, lamda, Upper_Energy, Exposure_Time, Slit_width, ...
                Slit_height, Light_source, Camera] = extractTableData(T);
            % lamda=str2double(lambda);
            % Prompt user to select a directory for saving data
            saveDirectory = pwd;
            saveDirectory=saveDirectory(1:end-4);
            saveDirectory=[saveDirectory '\Resolution_slit_width'];
            % Generate a filename based on the current settings and time
            filename = generateFilename(app,Light_source, Camera);

           % Define the full path to save the Excel file
            filePath = fullfile(saveDirectory, [filename, '.xlsx']);

            % Initialize channel data for spectrometer readings
            channel = [app.left_Pixel_Value.Value:(app.right_Pixel_Value.Value-app.left_Pixel_Value.Value+1)]';

            % Create headers for data sheets
            createHeaders(T, filePath);

            % Loop through each wavelength and perform measurements
            for ii = 1:length(lamda)
                an=questdlg(sprintf('Change the slit width to %d um and then press Yes',sw(ii)));
                while string(an)~=string('Yes')
                     an=questdlg(sprintf('Change the slit width to %d um and then press Yes',sw(ii)));
                end
                
                % Measure the spectrum for the current wavelength
                spect = captureSpectra(app, lamda(ii), Exposure_Time(ii))./Exposure_Time(ii);


                % Measure background signal
                bg = captureBackground(app, Exposure_Time(ii))./Exposure_Time(ii);

                % Calculate corrected spectrum
                spectcorr = spect - bg;

                % Write the data to Excel
                yy=interp1(lambd,theo_disp,lamda(ii))*10^9;
                
                wave=lamda(ii)+yy.*(channel-channel(round(length(channel)/2)));
                writeSpectraToExcel(filePath, spect, bg, ii, size(T,2),wave);

                % Plot the corrected spectrum
                plot(app.UIAxes, spectcorr(1:end), 'LineWidth', 1.5);
                % xlabel(app.UIAxes, 'Wavelength (nm)');

               
            end
            rang = sprintf(['A%d:A%d'], size(T,2)+1,size(T,2)+app.right_Pixel_Value.Value-app.left_Pixel_Value.Value);
            writematrix(channel, filePath, "Sheet", 'Data', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Background', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Corrected Data', 'Range', rang);
            writematrix(channel, filePath, "Sheet", 'Wavelength', 'Range', rang);


            % Write measurement parameters to the Excel file
            writeMeasurementParameters(filePath, T, lamda);

            % Re-enable UI components after process completion
            enableUIComponents(app);

            %% Sub-functions

            % Function to disable UI components
            function disableUIComponents(app)
                app.Spinner.Visible = 0;
                app.WhatdoyouwanttoseeButtonGroup.Visible = 0;
                
                app.Spinner_2.Visible = 0;
                app.UITable.ColumnEditable = false;
                app.RecordthisButton.Enable = 0;
                app.AddLinesButton.Enable = 0;
                app.RealTimeLabel.Enable = 0;
                app.SpectrometerParametersLabel.Enable = 0;
                app.TargetWavelengthEditField.Enable = 0;
                app.PresentWavelengthvalueEditField.Enable = 0;
                app.Button.Enable = 0;
                app.Button_2.Enable = 0;
                app.LookatmeasuredspectraButton.Enable = 0;
                app.SavespectraofaddedlinesButton.Visible=0;
                app.LookatspectraofaddedLinesButton.Visible = 0;
            end

            % Function to extract data from the table
            function [Species, Ionisation, lamda, Upper_Energy, Exposure_Time, Slit_width, Slit_height, Light_source, Camera] = extractTableData(T)
                Species = T{:, 1}';
                Ionisation = T{:, 2}';
                lamda = T{:, 3};
                Upper_Energy = T{:, 4}';
                Exposure_Time = T{:, 5}';
                Slit_width = T{:, 6}';
                Slit_height = T{:, 7}';
                Light_source = T{:, 8}';
                Camera = T{:, 9}';
            end

            % Function to generate a filename based on the light source, camera, and slit width
            function filename = generateFilename(app, Light_source, Camera)
                timestamp = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
     
                 filename = sprintf('%s_%s_%s',string("Laser"), string(Camera{1}), char(timestamp));
            end
            % Function to add specifications to the filename
            

            % Function to create headers in the Excel file
            % Function to create headers and write corresponding values for all rows in the table to the Excel file
            function createHeaders(T, filePath)
                % Define headers
                headers = ["Species", "Ionization", "Wavelength(nm)", "Upper Energy level(cm-1)", ...
                    "Exposure Time", "Slit Width", "Slit Height", "Light Source", ...
                    "Camera", "Pressure", "Resistance"];


                % Write headers to Excel, starting at cell A1
                hdr_rang=string(['A1:A' char(string(length(headers)))]);
                writematrix(headers', filePath, 'Sheet', 'Data', 'Range', hdr_rang);
                writematrix(headers', filePath, 'Sheet', 'Background', 'Range', hdr_rang);
                writematrix(headers', filePath, 'Sheet', 'Corrected Data', 'Range', hdr_rang);
                writematrix(headers', filePath, 'Sheet', 'Wavelength', 'Range', hdr_rang);

                % Determine the number of rows in T
                numRows = size(T, 1);

                % Extract all values from table T for the specified columns
                T1=rows2vars(T);
                T1=T1(:,2:end);
                if numRows<26
                    range=['B1:' 'B'+numRows-1 char(string(length(headers)))];
                else
                    range=['B1:' char(['B'+floor(numRows/26)-2 'A'+mod(numRows,26)]) char(string(length(headers)))];
                end
                writetable(T1, filePath,'Sheet','Data','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,'Sheet','Background','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,'Sheet','Corrected Data','Range',range,'WriteVariableNames', false);
                writetable(T1, filePath,'Sheet','Wavelength','Range',range,'WriteVariableNames', false);
            end


            % Function to measure spectra
            function spect = captureSpectra(app, lamda, exposureTime)
                
                OpenMirrorDropDownValueChanged(app);
                app.MirrorFacetowardsDropDown.Value = 'Calibration lamp';
                MirrorFacetowardsDropDownValueChanged(app);
                app.TargetWavelengthEditField.Value = lamda;
                CHANGEVALUEButtonValueChanged(app);
                spect = capture(app, exposureTime);
                spect = spect(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
            end

            % Function to capture background
            function bg = captureBackground(app, exposureTime)
                OpenMirrorDropDownValueChanged(app);
                app.MirrorFacetowardsDropDown.Value = 'Spectrometer';
                MirrorFacetowardsDropDownValueChanged(app);
                bg = capture(app, exposureTime);
                bg = bg(app.left_Pixel_Value.Value:app.right_Pixel_Value.Value);
            end
              % Function to capture calibrated lamp signal
           

            % Function to write spectra and wavelength to Excel
            function writeSpectraToExcel(filePath, spect, bg, ii, ll, wave)
    % Load dispersion data
    load dispersion.mat;
    tt=readtable('Calibration.xlsx');
   
    
    % Get the correct Excel column based on ii
    col = getExcelColumn(ii);

    % Define the row range
    startRow = ll + 1;
    endRow = app.right_Pixel_Value.Value - app.left_Pixel_Value.Value + ll;
    
    % Define the range in Excel notation
    range = sprintf('%s%d:%s%d', col, startRow, col, endRow);
   
    % Write data to different sheets in the Excel file
    writematrix(spect', filePath, "Sheet", 'Data', 'Range', range);
    writematrix(bg', filePath, "Sheet", 'Background', 'Range', range);
    writematrix(spect'-bg', filePath, "Sheet", 'Corrected Data', 'Range', range);
    writematrix(wave, filePath, "Sheet", 'Wavelength', 'Range', range);
end

% Function to convert column index (ii) to Excel-style column letters
function colName = getExcelColumn(ii)
    colName = '';
    while ii >= 0
        remainder = mod(ii, 26);
        colName = [char('A' + remainder), colName]; % Prepend character
        ii = floor(ii / 26) - 1; % Move to next place value
    end
end


            % Function to write measurement parameters
           function writeMeasurementParameters(filePath, T, lamda)
                range = ['A1:' 'A'+size(T,2) char(string(length(lamda) + 1))];
                T.Properties.VariableNames=[{'Species'} {'Ionization'} {'Wavelength(nm)'} {'Upper Energy level(cm-1)'} ...
                    {'Exposure Time'} {'Slit Width'} {'Slit Height'} {'Light Source'} {'Camera'} ...
                    {'Pressure'} {'Resistance'}];
                writetable(T, filePath, "Sheet", 'Measuring Parameters', 'Range', range);
            end

            % Function to re-enable UI components
            function enableUIComponents(app)
                app.SavespectraofaddedlinesButton.Value = 0;
                app.RecordthisButton.Enable = 1;
                app.AddLinesButton.Enable = 1;
                app.RealTimeLabel.Enable = 1;
                app.SpectrometerParametersLabel.Enable = 1;
                app.LookatmeasuredspectraButton.Enable = 1;
                app.LookatspectraofaddedLinesButton.Enable = 1;
                app.UITable.ColumnEditable = true;
                app.TargetWavelengthEditField.Enable = 1;
                app.PresentWavelengthvalueEditField.Enable = 1;
                app.Button.Enable = 1;
                app.Button_2.Enable = 1;
                app.SavespectraofaddedlinesButton.Visible=0;
                app.LookatspectraofaddedLinesButton.Visible = 0;
                app.SavespectraofaddedlinesButton.Enable=0;
                app.LookatspectraofaddedLinesButton.Enable = 0;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Channels')
            ylabel(app.UIAxes, 'Irradiance')
            zlabel(app.UIAxes, 'Z')
            app.MirrorFacetowardsDropDownLabel = uilabel(app.UIFigure);
            app.MirrorFacetowardsDropDownLabel.Text = 'Mirror Face towards';
            app.MirrorFacetowardsDropDown = uidropdown(app.UIFigure);
            app.MirrorFacetowardsDropDown.Items = {'Calibration lamp', 'Measuring lamp', 'Spectrometer'};
            app.MirrorFacetowardsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OpenMirrorDropDownValueChanged, true);
            app.MirrorFacetowardsDropDown.ValueChangedFcn = createCallbackFcn(app, @MirrorFacetowardsDropDownValueChanged, true);
            app.MirrorFacetowardsDropDown.Value = 'Calibration lamp';
            app.TargetWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.TargetWavelengthEditFieldLabel.Text = 'Target Wavelength';
            app.TargetWavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetWavelengthEditField.AllowEmpty = 'on';
            app.TargetWavelengthEditField.Value = [];
            app.PresentWavelengthvalueEditFieldLabel = uilabel(app.UIFigure);
            app.PresentWavelengthvalueEditFieldLabel.Text = 'Present Wavelength value';
            app.PresentWavelengthvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.PresentWavelengthvalueEditField.AllowEmpty = 'on';
            app.PresentWavelengthvalueEditField.Value = [];
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.UIFigure);
            app.nmLabel_2.Text = 'nm';
            app.SpectrometerParametersLabel = uilabel(app.UIFigure);
            app.SpectrometerParametersLabel.Text = 'Spectrometer Parameters';
            app.RadiationSelectionMirrorLabel = uilabel(app.UIFigure);
            app.RadiationSelectionMirrorLabel.Text = 'Radiation Selection Mirror';
            app.TecnicalParametersLabel = uilabel(app.UIFigure);
            app.TecnicalParametersLabel.Text = 'Tecnical Parameters';
            app.SlitWidthEditFieldLabel = uilabel(app.UIFigure);
            app.SlitWidthEditFieldLabel.Text = 'Slit Width';
            app.SlitWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlitWidthEditField.AllowEmpty = 'on';
            app.SlitWidthEditField.Value = [];
            app.SlitHeightEditFieldLabel = uilabel(app.UIFigure);
            app.SlitHeightEditFieldLabel.Text = 'Slit Height';
            app.SlitHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlitHeightEditField.AllowEmpty = 'on';
            app.SlitHeightEditField.Value = [];
            app.SlitAreaEditFieldLabel = uilabel(app.UIFigure);
            app.SlitAreaEditFieldLabel.Text = 'Slit Area';
            app.SlitAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlitAreaEditField.Value = 0.21;
            app.SlitParametersLabel = uilabel(app.UIFigure);
            app.SlitParametersLabel.Text = 'Slit Parameters';
            app.umLabel = uilabel(app.UIFigure);
            app.umLabel.Text = 'um';
            app.umLabel_2 = uilabel(app.UIFigure);
            app.umLabel_2.Text = 'um';
            app.umLabel_3 = uilabel(app.UIFigure);
            app.umLabel_3.Text = 'um';
            app.CHANGEVALUEButton = uibutton(app.UIFigure, 'state');
            app.CHANGEVALUEButton.ValueChangedFcn = createCallbackFcn(app, @CHANGEVALUEButtonValueChanged, true);
            app.CHANGEVALUEButton.Text = 'CHANGE VALUE';
            app.AddLinesButton = uibutton(app.UIFigure, 'state');
            app.AddLinesButton.ValueChangedFcn = createCallbackFcn(app, @AddLinesButtonValueChanged, true);
            app.AddLinesButton.Icon = fullfile(pathToMLAPP, 'add.png');
            app.AddLinesButton.Text = 'Add Lines';
            app.SavespectraofaddedlinesButton = uibutton(app.UIFigure, 'state');
            app.SavespectraofaddedlinesButton.ValueChangedFcn = createCallbackFcn(app, @SavespectraofaddedlinesButtonValueChanged2, true);
            app.SavespectraofaddedlinesButton.Enable = 'off';
            app.SavespectraofaddedlinesButton.Text = 'Save spectra of added lines';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureTimeEditField.Value = 0.2;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'start.jpg');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'stop.jpg');
            app.Button_2.Text = '';
            app.LightSourceEditFieldLabel = uilabel(app.UIFigure);
            app.LightSourceEditFieldLabel.Text = 'Light Source';
            app.LightSourceEditField = uieditfield(app.UIFigure, 'text');
            app.LightSourceEditField.Value = 'Laser';
            app.CameraDropDownLabel = uilabel(app.UIFigure);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.UIFigure);
            app.CameraDropDown.Items = {'CMOS PCO', 'OMA 2', 'QUIK'};
            app.CameraDropDown.Value = 'CMOS PCO';
            app.RecordthisButton = uibutton(app.UIFigure, 'state');
            app.RecordthisButton.ValueChangedFcn = createCallbackFcn(app, @RecordthisButtonValueChanged, true);
            app.RecordthisButton.Text = 'Record this';
            app.LookatmeasuredspectraButton = uibutton(app.UIFigure, 'push');
            app.LookatmeasuredspectraButton.ButtonPushedFcn = createCallbackFcn(app, @LookatmeasuredspectraButtonPushed, true);
            app.LookatmeasuredspectraButton.Text = 'Look at measured spectra';
            app.WhatdoyouwanttoseeButtonGroup = uibuttongroup(app.UIFigure);
            app.WhatdoyouwanttoseeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhatdoyouwanttoseeButtonGroupSelectionChanged, true);
            app.WhatdoyouwanttoseeButtonGroup.Title = 'What do you want to see?';
            app.WhatdoyouwanttoseeButtonGroup.Visible = 'off';
            app.LampDataButton = uiradiobutton(app.WhatdoyouwanttoseeButtonGroup);
            app.LampDataButton.Text = 'Lamp Data';
            app.LampDataButton.Value = true;
            app.CalibrationLampDataButton = uiradiobutton(app.WhatdoyouwanttoseeButtonGroup);
            app.CalibrationLampDataButton.Text = 'Calibration Lamp Data';
            app.CalibratedDataButton = uiradiobutton(app.WhatdoyouwanttoseeButtonGroup);
            app.CalibratedDataButton.Text = 'Calibrated Data';
            app.filenameEditField = uieditfield(app.WhatdoyouwanttoseeButtonGroup, 'text');
            app.filenameEditField.Editable = 'off';
            app.filenameEditField.Visible = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false];
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.Visible = 'off';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @WhatdoyouwanttoseeButtonGroupSelectionChanged, true);
            app.Spinner.Visible = 'off';
            app.Spinner.Value = 1;
            app.RealTimeLabel = uilabel(app.UIFigure);
            app.RealTimeLabel.Text = 'Real Time';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'In order to perform other functions, first STOP the Real Time'};
            app.LookatspectraofaddedLinesButton = uibutton(app.UIFigure, 'push');
            app.LookatspectraofaddedLinesButton.ButtonPushedFcn = createCallbackFcn(app, @LookatspectraofaddedLinesButtonPushed, true);
            app.LookatspectraofaddedLinesButton.Enable = 'off';
            app.LookatspectraofaddedLinesButton.Text = 'Look at spectra of added Lines';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @LookatspectraofaddedLinesButtonPushed, true);
            app.Spinner_2.Visible = 'off';
            app.Spinner_2.Value = 1;
            app.CurrentEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.UIFigure, 'text');
            app.mALabel = uilabel(app.UIFigure);
            app.mALabel.Text = 'mA';
            app.CameraParametersLabel = uilabel(app.UIFigure);
            app.CameraParametersLabel.Text = 'Camera Parameters';
            app.left_Pixel = uilabel(app.UIFigure);
            app.left_Pixel.Text = 'Left pixel';
            app.left_Pixel_Value = uieditfield(app.UIFigure, 'numeric');
            app.left_Pixel_Value.Limits = [100 2000];
            app.left_Pixel_Value.Editable = 'off';
            app.left_Pixel_Value.Value = 100;
            app.right_Pixel = uilabel(app.UIFigure);
            app.right_Pixel.Text = 'Right Pixel';
            app.right_Pixel_Value = uieditfield(app.UIFigure, 'numeric');
            app.right_Pixel_Value.Limits = [100 2048];
            app.right_Pixel_Value.Editable = 'off';
            app.right_Pixel_Value.Value = 1948;
            app.top_Pixel = uilabel(app.UIFigure);
            app.top_Pixel.Text = 'Top Pixel';
            app.bottom_Pixel = uilabel(app.UIFigure);
            app.bottom_Pixel.Text = 'Bottom Pixel';
            app.top_Pixel_Value = uieditfield(app.UIFigure, 'numeric');
            app.top_Pixel_Value.Limits = [1 2048];
            app.top_Pixel_Value.Value = 1;
            app.bottom_Pixel_Value = uieditfield(app.UIFigure, 'numeric');
            app.bottom_Pixel_Value.Limits = [1 2048];
            app.bottom_Pixel_Value.Value = 2048;
            app.CalibrateCurvatureButton = uibutton(app.UIFigure, 'push');
            app.CalibrateCurvatureButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateCurvatureButtonPushed, true);
            app.CalibrateCurvatureButton.Text = 'Calibrate Curvature';
            app.ChoosefittingmodelDropDownLabel = uilabel(app.UIFigure);
            app.ChoosefittingmodelDropDownLabel.Text = 'Choose fitting model';
            app.ChoosefittingmodelDropDown = uidropdown(app.UIFigure);
            app.ChoosefittingmodelDropDown.Items = {'Circular', 'Parabolic'};
            app.ChoosefittingmodelDropDown.Value = 'Parabolic';
            app.PixelssaturatedEditFieldLabel = uilabel(app.UIFigure);
            app.PixelssaturatedEditFieldLabel.Text = 'Pixels saturated';
            app.PixelssaturatedEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'If the no of pixels saturated are more than 100, it is advised to repeat the measurement.'};
            app.IdentifyLinesButton = uibutton(app.UIFigure, 'push');
            app.IdentifyLinesButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyLinesButtonPushed, true);
            app.IdentifyLinesButton.Text = 'Identify Lines';
            app.FacetotheCalibrationlampButton = uibutton(app.UIFigure, 'push');
            app.FacetotheCalibrationlampButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMirrorDropDownValueChanged, true);
            app.FacetotheCalibrationlampButton.Text = 'Face to the Calibrationlamp';
            app.ResolutionMeasurementButton = uibutton(app.UIFigure, 'state');
            app.ResolutionMeasurementButton.ValueChangedFcn = createCallbackFcn(app, @ResolutionMeasurementButtonValueChanged, true);
            app.ResolutionMeasurementButton.Text = 'Resolution Measurement';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Optical_Parameters_Change_new_mirror

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end