classdef identify_lines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PressurePaEditField            matlab.ui.control.NumericEditField
        PressurePaEditFieldLabel       matlab.ui.control.Label
        SavepartialspectraButton       matlab.ui.control.Button
        SaveAxesButton                 matlab.ui.control.Button
        StartButton                    matlab.ui.control.StateButton
        Slider                         matlab.ui.control.Slider
        ShiftinchannelsEditField       matlab.ui.control.NumericEditField
        ShiftinchannelsEditFieldLabel  matlab.ui.control.Label
        UITable_3                      matlab.ui.control.Table
        ChoosehowyouwanttoidentifylinesButtonGroup  matlab.ui.container.ButtonGroup
        ChooseMultipleexposureTimeandoneCurrentButton  matlab.ui.control.RadioButton
        ChooseMultiplecurrentandoneExposureTimeButton  matlab.ui.control.RadioButton
        UITable_2                      matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function STARTButtonPushed(app, event)


        end

        % Callback function
        function CurrentmAListBoxValueChanged(app, event)
            
        end

        % Callback function: UITable, UITable_2, UITable_3
        function UITableSelectionChanged(app, event)
    % Get selections
    selection_curr = app.UITable.Selection;
    selection_ept = app.UITable_2.Selection;
    selection_synt = app.UITable_3.Selection;

    % Ensure all selections are made
    if isempty(selection_synt) || isempty(selection_curr) || isempty(selection_ept)
        return; % Exit if any selection is missing
    end

    % Clear previous plot and reset slider
    cla(app.UIAxes);
    app.Slider.Value = 0;

    % Extract selected indices
    selection_curr = selection_curr(:, 1);
    selection_ept = selection_ept(:, 1);
    selection_synt = selection_synt(:, 1);

    % Load file names
    if exist('name.mat', 'file')
        load('name.mat', 'name');
    else
        uialert(app.UIFigure, 'name.mat not found.', 'File Error');
        return;
    end

    % Initialize arrays
    numFiles = length(name);
    exp_time = [];
    current_value = zeros(1, numFiles);
      ii = size(readtable(name(1)), 2);  
    colName = getExcelColumn(ii); % Get the corresponding Excel column name

    % Read data from files
   for ii = 1:length(name)
        % data = readtable(name(ii), 'Sheet', 'Data');
        et= readtable(name(ii), 'Sheet', 'Corrected Data', 'Range', ['B5:' colName '5']);
   valid_et=et{1, :};
        exp_time = [exp_time unique(valid_et)];

        % Extract current value from filename (assuming '__' followed by digits)
        match = regexp(name(ii), '__(\d+)', 'tokens');
        if ~isempty(match)
            current_value(ii) = str2double(match{1}{1});
        end
   end
   exp_time=exp_time(~isnan(exp_time));
    % Round current values to nearest 10
    current_value = round(current_value ./ 10) .* 10;

    % Retrieve UI table data
    curr_data = app.UITable.Data{:,:};
    ept_data = app.UITable_2.Data{:,:};

    % Find matching indices
    matching_idx = ismember(current_value, curr_data(selection_curr)) & ...
                   ismember(exp_time, ept_data(selection_ept));
    idx = find(matching_idx);

    % Exit if no matches found
    if isempty(idx)
        uialert(app.UIFigure, 'No matching data found.', 'Selection Error');
        return;
    end

    % Prepare plot
    hold(app.UIAxes, 'on');
    legendEntries = strings(1, length(idx));

    % Retrieve syntony data
    syntony_selection = app.UITable_3.Data{selection_synt, 1};

    % Plot data for selected indices
    for ii = 1:length(idx)
        % Read data for the current file
        
        ww = readtable(name(idx(ii)), 'Sheet', 'Measuring Parameters');
        pres=ww.Pressure;
        ww=ww.Wavelength_nm_;
            
            if sum(ww==syntony_selection)==0
            else
                syntony_value = ww(find(ww==syntony_selection));
            % Verify syntony match
            if syntony_selection == syntony_value
                colName = getExcelColumn(find(ww==syntony_selection)+1); % Get the corresponding Excel column name
                app.PressurePaEditField.Value=pres(find(ww==syntony_selection));
                app.PressurePaEditField.Visible=1;
                app.PressurePaEditFieldLabel.Visible=1;
                 y = readtable(name(idx(ii)), 'Sheet', 'Corrected Data', 'Range', [colName '12:' colName '1859']);
                plot(app.UIAxes, y{:,1}, 'LineWidth', 2);

                % Add legend entry
                legendEntries(ii) = sprintf('%d mA ; %d s', current_value(idx(ii)), exp_time(idx(ii)));
            end
            end
        
    end

    % Configure plot aesthetics
    grid(app.UIAxes, 'on');
    grid(app.UIAxes, 'minor');
    legend(app.UIAxes, legendEntries, 'FontSize', 12, 'Location', 'best');
    hold(app.UIAxes, 'off');

    % Update slider function
    SliderValueChanged(app, event);
    function colName = getExcelColumn(ii)
    colName = '';
    while ii > 0
        remainder = mod(ii - 1, 26);
        colName = [char('A' + remainder), colName]; % Prepend character
        ii = floor((ii - 1) / 26);
    end
end
        end

        % Selection changed function: 
        % ChoosehowyouwanttoidentifylinesButtonGroup
        function ChoosehowyouwanttoidentifylinesButtonGroupSelectionChanged(app, event)

            cla(app.UIAxes)
            if app.ChooseMultiplecurrentandoneExposureTimeButton.Value==1
                app.UITable_2.Multiselect="off";
                app.UITable.Multiselect="on";
            end
            if app.ChooseMultipleexposureTimeandoneCurrentButton.Value==1
                app.UITable.Multiselect="off";
                app.UITable_2.Multiselect="on";
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
           
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
   % Get slider value and update edit field
    value = app.Slider.Value;
    app.ShiftinchannelsEditField.Value = value;

    % Ensure all selections are made
    selection_curr = app.UITable.Selection;
    selection_ept = app.UITable_2.Selection;
    selection_synt = app.UITable_3.Selection;

    if isempty(selection_synt) || isempty(selection_curr) || isempty(selection_ept)
        return; % Exit function if any selection is missing
    end

    % Remove existing xlines if present
    xlineHandles = findobj(app.UIAxes, 'Type', 'ConstantLine');
    if ~isempty(xlineHandles)
        delete(xlineHandles);
    end

    % Load spectrum data
    T = readtable('lines.xlsx', 'Sheet', 'Milan_low_lying_lines');
    species = T{1:3374, 1};
    wavelength = T{1:3374, 2};
    intensity = T{1:3374, 4};
    comments = T{1:3374, "Comments"};

    % Extract element and ionization from species
    numSpecies = length(species);
    element = strings(numSpecies, 1);
    ionization = strings(numSpecies, 1);

    for ii = 1:numSpecies
        if ischar(species{ii}) || isstring(species{ii})
            element(ii) = string(species{ii}(1:2));
            ionization(ii) = string(species{ii}(4:end));
        end
    end

    % Get syntony value
    syntony = app.UITable_3.Data{selection_synt(1), 1};

    % Load dispersion data
    if exist('dispersion.mat', 'file')
        load('dispersion.mat', 'lambd', 'theo_disp');
    else
        uialert(app.UIFigure, 'dispersion.mat not found.', 'File Error');
        return;
    end

    % Compute initial dispersion factor
    initial_disp = interp1(lambd, theo_disp, syntony) * 1e9;

    % Find spectral lines near the syntony value (±3 nm)
    wavelength_diff = abs(wavelength - syntony);
    valid_idx = wavelength_diff < 3;

    % Compute x-line positions based on shift and dispersion
    lineHandles = findobj(app.UIAxes, 'Type', 'Line');
    if isempty(lineHandles)
        return; % Exit if no spectral line plots exist
    end

    center_position = length(lineHandles(1).YData) / 2 + value;
    x_positions = ((wavelength(valid_idx) - syntony) / initial_disp) + center_position;

    % Retrieve valid species, comments, and intensity values
    valid_species = species(valid_idx);
    valid_wavelengths = wavelength(valid_idx);
    valid_intensities = intensity(valid_idx);
    valid_comments = comments(valid_idx);

    % Annotate detected peaks
    for jj = 1:length(x_positions)
        annotation_text = sprintf('%s Wavelength: %.2f Intensity: %.2f; %s', ...
            valid_species{jj}, valid_wavelengths(jj), valid_intensities(jj), valid_comments{jj});

        xl = xline(app.UIAxes, x_positions(jj), '-', annotation_text);
        xl.Annotation.LegendInformation.IconDisplayStyle = "off";
    end

    % Adjust x-axis limits
    xlim(app.UIAxes, [1, length(lineHandles(1).YData)]);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            % Start button value check
    value = app.StartButton.Value; 

    % Check if 'name.mat' exists and prompt the user for an update
    updateList = true;
    if isfile('name.mat')
        choice = questdlg('Do you want to update the name list?', ...
                          'Update Confirmation', ...
                          'Yes', 'No', 'No');
        updateList = strcmp(choice, 'Yes');
    end

    % If update is required or 'name.mat' doesn't exist
    if updateList
        [files, path] = uigetfile('*.xlsx', 'Select Excel Files', 'MultiSelect', 'on');

        % If user cancels selection, exit
        if isequal(files, 0)
            disp('File selection canceled. No changes made.');
            return;
        end

        % Ensure 'files' is in cell format
        if ischar(files)
            files = {files};
        end
        
        % Convert file names to a string array and save to 'name.mat'
        name = string(files);
        save('name.mat', 'name');
        disp('File names successfully updated and saved to name.mat.');
    end

    % Load the saved file list
    load name.mat;

    % Determine the number of columns in the first file
    ii = size(readtable(name(1)), 2);  
    colName = getExcelColumn(ii); % Get the corresponding Excel column name

    % Identify the file with the most valid (non-NaN) data in row 3
    cnt = 0;
    ind = 1;
    for ii = 1:length(name)
        data = readtable(name(ii), 'Sheet', 'Corrected Data', 'Range', ['B3:' colName '3']);
        validValues = ~isnan(data{1, :});
        numValid = sum(validValues);

        if numValid > cnt
            ind = ii;
            cnt = numValid;
        end
    end

    % Read selected file's valid data from row 3


   et= readtable(name(ind), 'Sheet', 'Corrected Data', 'Range', ['B5:' colName '5']);
   valid_et=et{1, :};
    % Update UI table with selected data
    
    % Extract exposure times and currents
    exp_time = [];
    current_value = zeros(1, length(name));
selectedData=[];
    for ii = 1:length(name)
        % data = readtable(name(ii), 'Sheet', 'Data');

         et= readtable(name(ii), 'Sheet', 'Corrected Data', 'Range', ['B5:' colName '5']);
   valid_et=et{1, :};
        exp_time = [exp_time, unique(valid_et)];

        % Extract current value from filename (assuming '__' followed by digits)
        match = regexp(name(ii), '__(\d+)', 'tokens');
        if ~isempty(match)
            current_value(ii) = str2double(match{1}{1});
        end

         selectedData1 = readtable(name(ii), 'Sheet', 'Corrected Data', 'Range', ['B3:' colName '3']);
    validValues = selectedData1{1, :};
    selectedData = [selectedData validValues(~isnan(validValues))]; % Keep only valid data
    end
    app.UITable_3.Data = table(unique(selectedData'));

    % Process exposure times and currents
    ept = unique(exp_time);
    ept(ept > 5) = NaN; % Remove exposure times above 5
    ept = ept(~isnan(ept)); % Remove NaN values

    curr = unique(round(current_value ./ 10)) * 10; % Round currents to nearest 10

    % Update UI tables with current values
    app.UITable.Data = table(curr');
    app.UITable_2.Data = table(ept');

    % Make UI elements visible
    app.UITable.Visible = 'on';
    app.UITable_2.Visible = 'on';
    app.UITable_3.Visible = 'on';
    app.Slider.Visible = 'on';
    app.Slider.Value = 0;
    app.ShiftinchannelsEditField.Value = app.Slider.Value;
    app.ShiftinchannelsEditField.Visible = 'on';
    app.ChoosehowyouwanttoidentifylinesButtonGroup.Visible = 'on';

    % Set selection behavior for UI tables
    if app.ChooseMultiplecurrentandoneExposureTimeButton.Value == 1
        app.UITable_2.Multiselect = 'on';
        app.UITable.Multiselect = 'off';
    else
        app.UITable.Multiselect = 'on';
        app.UITable_2.Multiselect = 'off';
    end

    % Hide start button and show save buttons
    app.StartButton.Visible = 'off';
    app.SaveAxesButton.Visible = 'on';
    app.SavepartialspectraButton.Visible = 'on';

% Function to convert column index (ii) to Excel-style column letters
function colName = getExcelColumn(ii)
    colName = '';
    while ii > 0
        remainder = mod(ii - 1, 26);
        colName = [char('A' + remainder), colName]; % Prepend character
        ii = floor((ii - 1) / 26);
    end
end
        end

        % Button pushed function: SaveAxesButton
        function SaveAxesButtonPushed(app, event)
    % Get selected rows from UI tables
    selection_curr = app.UITable.Selection;
    selection_ept = app.UITable_2.Selection;
    selection_synt = app.UITable_3.Selection;

    % Retrieve data from UI tables
    currTable = app.UITable.Data;
    eptTable = app.UITable_2.Data;
    syntTable = app.UITable_3.Data;

    % Check if selections are made in all three tables
    if isempty(selection_synt) || isempty(selection_curr) || isempty(selection_ept)
        uialert(app.UIFigure, 'Please select data from all tables.', 'Selection Error');
        return;
    end

    syntony = syntTable{selection_synt(1), 1}; % Selected syntony value

    % Ensure the output folder exists
    folderName = 'Identified lines';
    if ~exist(folderName, 'dir')
        mkdir(folderName);
    end

    % Find all plot lines and xLines in UIAxes
    plotLines = findall(app.UIAxes, 'Type', 'Line');
    xLines = findall(app.UIAxes, 'Type', 'ConstantLine');

    % Validate plot count
    numCombinations = size(selection_curr, 1) * size(selection_ept, 1);
    if length(plotLines) ~= numCombinations
        uialert(app.UIFigure, 'Mismatch between plots and selections!', 'Error');
        return;
    end

    % Load dispersion data
    if exist('dispersion.mat', 'file')
        load dispersion.mat lambd theo_disp;
    else
        uialert(app.UIFigure, 'dispersion.mat not found.', 'File Error');
        return;
    end

    % Process each selected combination
    plotIndex = 1;
    for i = 1:size(selection_curr, 1)
        for j = 1:size(selection_ept, 1)
            % Get selected current and exposure time
            curr_value = currTable{selection_curr(i, 1), :};
            ept_value = eptTable{selection_ept(j, 1), :};
            pres=app.PressurePaEditField.Value;
            % Generate a valid figure filename
            figName = sprintf('synt_%s_%s_mA_%s_s_%s_Pa', ...
                strrep(string(syntony), '.', '_'), ...
                strrep(string(curr_value), '.', '_'), ...
                strrep(string(ept_value), '.', '_'), ...
                strrep(string(pres), '.', '_'));

            % Create figure and axis
            myFig = figure('Visible', 'off');
            ax = axes('Parent', myFig);
            hold(ax, 'on');

            % Get corresponding plot line
            originalPlot = plotLines(plotIndex);
            plotIndex = plotIndex + 1;

            % Find xLines within the valid range
            validXLines = xLines(arrayfun(@(x) x.Value > 1 && x.Value < length(originalPlot.YData), xLines));
            if isempty(validXLines)
                warning('No valid xLines found for plot %d. Skipping.', plotIndex - 1);
                continue;
            end

            % Process wavelength annotations
            pattern = 'Wavelength:\s([\d.]+)';
            wavelength = parseWavelength(validXLines(1).Label, pattern);
            disp_factor = interp1(lambd, theo_disp, wavelength, 'linear', 'extrap');

            % Adjust X-axis data based on dispersion
            adjustedX = (originalPlot.XData - validXLines(1).Value) .* disp_factor .* 1e9 + wavelength;

            % Plot adjusted data
            plot(ax, adjustedX, originalPlot.YData, ...
                'Color', originalPlot.Color, ...
                'LineWidth', originalPlot.LineWidth, ...
                'LineStyle', originalPlot.LineStyle);

            % Copy and preserve xLine properties
            for k = 1:length(validXLines)
                new_wavelength = parseWavelength(validXLines(k).Label, pattern);
                xline(ax, new_wavelength, ...
                    'Label', validXLines(k).Label, ...
                    'Color', validXLines(k).Color, ...
                    'LineStyle', validXLines(k).LineStyle, ...
                    'LineWidth', validXLines(k).LineWidth);
            end

            % Adjust plot appearance
            xlabel(ax, app.UIAxes.XLabel.String);
            ylabel(ax, app.UIAxes.YLabel.String);
            grid(ax, 'on');
            hold(ax, 'off');
            xlim([adjustedX(1) adjustedX(end)]);
            % Save the figure
            savefig(myFig, fullfile(folderName, [figName, '.fig']));
            close(myFig); % Prevent UI clutter
        end
    end
        % Helper function to extract wavelength from annotation
        function wavelength = parseWavelength(annotation, pattern)
            match = regexp(annotation, pattern, 'tokens', 'once');
            if ~isempty(match)
                wavelength = str2double(match{1});
            else
                error('Wavelength not found in annotation.');
            end
            end
        end

        % Button pushed function: SavepartialspectraButton
        function SavepartialspectraButtonPushed(app, event)
    % Ensure all selections are made
    selection_curr = app.UITable.Selection;
    selection_ept = app.UITable_2.Selection;
    selection_synt = app.UITable_3.Selection;

    if isempty(selection_curr) || isempty(selection_ept) || isempty(selection_synt)
        uialert(app.UIFigure, 'Please select data from all tables.', 'Selection Error');
        return;
    end

    % Process selections
    selection_synt = selection_synt(:, 1);
    selection_curr = selection_curr(:, 1);
    selection_ept = selection_ept(:, 1);

    % Find the most recent plotted line (assumed spectral data)
    xlineHandles = findobj(app.UIAxes, 'Type', 'Line');
    if isempty(xlineHandles)
        uialert(app.UIFigure, 'No plotted data found.', 'Error');
        return;
    end
    xlineHandles = xlineHandles(end); % Get the last plotted line

    % Prompt user for channel range
    prompt = {'Enter the starting channel:', 'Enter the last channel:'};
    dlg_title = 'Enter Range of Channels to Extract';
    num_lines = 1;
    default_ans = {'0', '0'};
    user_input = inputdlg(prompt, dlg_title, num_lines, default_ans);

    % Validate user input
    if isempty(user_input) || any(cellfun(@isempty, user_input))
        uialert(app.UIFigure, 'Invalid input. Please enter numeric values.', 'Input Error');
        return;
    end

    % Convert inputs to numeric
    starting_channel = str2double(user_input{1});
    last_channel = str2double(user_input{2});

    if isnan(starting_channel) || isnan(last_channel) || starting_channel < 1 || last_channel < starting_channel
        uialert(app.UIFigure, 'Invalid channel range. Ensure valid numeric input.', 'Input Error');
        return;
    end

    % Extract data within the specified range
    xx = xlineHandles.XData;
    yy = xlineHandles.YData;

    % Ensure valid indexing
    if last_channel > length(xx)
        last_channel = length(xx);
    end

    xx = xx(starting_channel:last_channel);
    yy = yy(starting_channel:last_channel);

    % Retrieve metadata values
    syntTable = app.UITable_3.Data;
    
    syntony = syntTable{selection_synt, 1};
   
            
 excelName = sprintf('synt_%s.xlsx', strrep(string(syntony), '.', '_'));

    eptTable = app.UITable_2.Data;
    ept = eptTable{max(selection_ept), 1};

    currTable = app.UITable.Data;
    curr = currTable{max(selection_curr), 1};

    % Prepare sheet name
    sheetName = sprintf('%d_to_%d_channel', starting_channel, last_channel);

    % Prepare data for Excel
    output_data = [xx(:), yy(:)];
    pres=app.PressurePaEditField.Value;
    metadata = {[], []; 'Current (mA):', curr; 'Exposure Time (s):', ept; 'Pressure (Pa):' pres};

    % Ensure output folder exists
    folderName = 'Identified lines';
    if ~exist(folderName, 'dir')
        mkdir(folderName);
    end

    % Extract annotations within range
    NdHandles = findobj(app.UIAxes, 'Type', 'ConstantLine');
    annotations = {}; 

    for i = 1:numel(NdHandles)
        lineX = NdHandles(i).Value; 
        if lineX >= xx(1) && lineX <= xx(end)
            label = get(NdHandles(i), 'Label');
            annotations{end + 1, 1} = label;
            annotations{end, 2} = num2str(lineX);
        end
    end
    
    % Append annotations to metadata
    if ~isempty(annotations)
        metadata = [metadata; {'Annotations:', 'Channels:'}; annotations];
    end

    % Save data to Excel
    % filePath = fullfile(folderName, excelName)
    writematrix(output_data, fullfile(folderName, excelName),'Sheet', sheetName, 'Range', 'A1');
    writecell(metadata, fullfile(folderName, excelName), 'Sheet', sheetName, 'Range', 'C1');

    disp(['Data saved to: ', fullfile(folderName, excelName)]);

    % Call SaveAxesButtonPushed function
    SaveAxesButtonPushed(app, event);
        end

        % Cell edit callback: UITable_3
        function UITable_3CellEdit(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Channels')
            ylabel(app.UIAxes, 'Irradiance')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Current (mA)'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Visible = 'off';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Exposure Time (s)'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable_2.Visible = 'off';
            app.ChoosehowyouwanttoidentifylinesButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosehowyouwanttoidentifylinesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosehowyouwanttoidentifylinesButtonGroupSelectionChanged, true);
            app.ChoosehowyouwanttoidentifylinesButtonGroup.Title = 'Choose how you want to identify lines';
            app.ChoosehowyouwanttoidentifylinesButtonGroup.Visible = 'off';
            app.ChooseMultiplecurrentandoneExposureTimeButton = uiradiobutton(app.ChoosehowyouwanttoidentifylinesButtonGroup);
            app.ChooseMultiplecurrentandoneExposureTimeButton.Text = 'Choose Multiple current and one Exposure Time';
            app.ChooseMultiplecurrentandoneExposureTimeButton.Value = true;
            app.ChooseMultipleexposureTimeandoneCurrentButton = uiradiobutton(app.ChoosehowyouwanttoidentifylinesButtonGroup);
            app.ChooseMultipleexposureTimeandoneCurrentButton.Text = 'Choose Multiple exposure Time and one Current';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Select Syntony'};
            app.UITable_3.RowName = {};
            app.UITable_3.CellEditCallback = createCallbackFcn(app, @UITable_3CellEdit, true);
            app.UITable_3.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable_3.Multiselect = 'off';
            app.UITable_3.Visible = 'off';
            app.ShiftinchannelsEditFieldLabel = uilabel(app.UIFigure);
            app.ShiftinchannelsEditFieldLabel.Visible = 'off';
            app.ShiftinchannelsEditFieldLabel.Text = 'Shift in  channels';
            app.ShiftinchannelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShiftinchannelsEditField.Limits = [-5000 5000];
            app.ShiftinchannelsEditField.Editable = 'off';
            app.ShiftinchannelsEditField.Visible = 'off';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-1000 500];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Visible = 'off';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.SaveAxesButton = uibutton(app.UIFigure, 'push');
            app.SaveAxesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAxesButtonPushed, true);
            app.SaveAxesButton.Visible = 'off';
            app.SaveAxesButton.Text = 'Save Axes';
            app.SavepartialspectraButton = uibutton(app.UIFigure, 'push');
            app.SavepartialspectraButton.ButtonPushedFcn = createCallbackFcn(app, @SavepartialspectraButtonPushed, true);
            app.SavepartialspectraButton.Visible = 'off';
            app.SavepartialspectraButton.Text = 'Save partial spectra';
            app.PressurePaEditFieldLabel = uilabel(app.UIFigure);
            app.PressurePaEditFieldLabel.Visible = 'off';
            app.PressurePaEditFieldLabel.Text = 'Pressure (Pa)';
            app.PressurePaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressurePaEditField.Editable = 'off';
            app.PressurePaEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = identify_lines

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end